classdef RSFit4000 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Step1LoadingandUnloadingStiffnessTab  matlab.ui.container.Tab
        SaveOutputButton                matlab.ui.control.Button
        FileName_Step1Output            matlab.ui.control.EditField
        StartandEndofSteadyStateShearPanel  matlab.ui.container.Panel
        ClearPointsButton_4             matlab.ui.control.Button
        text48_5                        matlab.ui.control.Label
        text47_5                        matlab.ui.control.Label
        UnloadStartMu                   matlab.ui.control.EditField
        UnloadStartDisp                 matlab.ui.control.EditField
        text48_4                        matlab.ui.control.Label
        text47_4                        matlab.ui.control.Label
        PostLoadingMu                   matlab.ui.control.EditField
        PostLoadingDisp                 matlab.ui.control.EditField
        thepointwhenloadingiscompleteLabel  matlab.ui.control.Label
        thepointbeforeunloadingLabel    matlab.ui.control.Label
        SetEndofSteadyStateButton       matlab.ui.control.Button
        SetStartofSteadyStateButton     matlab.ui.control.Button
        LoadingPanel                    matlab.ui.container.Panel
        text55_3                        matlab.ui.control.Label
        Point1IndexValue_2              matlab.ui.control.EditField
        text48_7                        matlab.ui.control.Label
        Point2IndexValue_2              matlab.ui.control.EditField
        text48_6                        matlab.ui.control.Label
        ClearPointsButton_2             matlab.ui.control.Button
        SetPoint2Button_2               matlab.ui.control.Button
        text50_2                        matlab.ui.control.Label
        text49_2                        matlab.ui.control.Label
        text48_2                        matlab.ui.control.Label
        text47_2                        matlab.ui.control.Label
        FitTrendButton_2                matlab.ui.control.Button
        SetPoint1Button_2               matlab.ui.control.Button
        Point2MuValue_2                 matlab.ui.control.EditField
        Point1MuValue_2                 matlab.ui.control.EditField
        Point2SlipValue_2               matlab.ui.control.EditField
        latex_Slope_2                   matlab.ui.control.Label
        SlopeValue_2                    matlab.ui.control.EditField
        Point1SlipValue_2               matlab.ui.control.EditField
        UnloadingPanel                  matlab.ui.container.Panel
        text55_2                        matlab.ui.control.Label
        Point1IndexValue_3              matlab.ui.control.EditField
        text48_9                        matlab.ui.control.Label
        Point2IndexValue_3              matlab.ui.control.EditField
        text48_8                        matlab.ui.control.Label
        ClearPointsButton_3             matlab.ui.control.Button
        SetPoint2Button_3               matlab.ui.control.Button
        text50_3                        matlab.ui.control.Label
        text49_3                        matlab.ui.control.Label
        text48_3                        matlab.ui.control.Label
        text47_3                        matlab.ui.control.Label
        FitTrendButton_3                matlab.ui.control.Button
        SetPoint1Button_3               matlab.ui.control.Button
        Point2MuValue_3                 matlab.ui.control.EditField
        Point1MuValue_3                 matlab.ui.control.EditField
        Point2SlipValue_3               matlab.ui.control.EditField
        latex_Slope_3                   matlab.ui.control.Label
        SlopeValue_3                    matlab.ui.control.EditField
        Point1SlipValue_3               matlab.ui.control.EditField
        ResetFiguresButton              matlab.ui.control.Button
        UpdateDropdownsButton           matlab.ui.control.Button
        Eff_NormalDropDownLabel         matlab.ui.control.Label
        Eff_NormalDropDown              matlab.ui.control.DropDown
        IndexDropDownLabel              matlab.ui.control.Label
        FrictionDropDownLabel           matlab.ui.control.Label
        DisplacementmmDropDownLabel     matlab.ui.control.Label
        TimesDropDownLabel              matlab.ui.control.Label
        IndexDropDown                   matlab.ui.control.DropDown
        FrictionDropDown                matlab.ui.control.DropDown
        DisplacementmmDropDown          matlab.ui.control.DropDown
        TimesDropDown                   matlab.ui.control.DropDown
        PlotDataButton                  matlab.ui.control.Button
        UIAxes_Stiffness                matlab.ui.control.UIAxes
        Step2PickEventsTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        VelocitySigFigEditField         matlab.ui.control.NumericEditField
        NumberofSigniciantFiguresforVelocitiesEditFieldLabel  matlab.ui.control.Label
        RowNumberEditField              matlab.ui.control.NumericEditField
        RowNumberEditFieldLabel         matlab.ui.control.Label
        EventFileNameEditField_2        matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        text46                          matlab.ui.control.Label
        HoldTimesEditField              matlab.ui.control.EditField
        PickSlideHoldSlideButton        matlab.ui.control.Button
        DeleteRowButton                 matlab.ui.control.Button
        OutputofStep1Label              matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        OutputofStep1EditField          matlab.ui.control.EditField
        SaveTableButton                 matlab.ui.control.Button
        AcceptPickButton                matlab.ui.control.Button
        PickVelocityStepButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        Step3WindowandDetrendTab        matlab.ui.container.Tab
        PresetDisplacementAmountmEditField  matlab.ui.control.NumericEditField
        PresetDisplacementAmountmEditFieldLabel  matlab.ui.control.Label
        SaveFileNameEditField           matlab.ui.control.EditField
        FileNameLabel_2                 matlab.ui.control.Label
        EventFileNameEditField          matlab.ui.control.EditField
        FileNameLabel_7                 matlab.ui.control.Label
        EventListBox_Detrend            matlab.ui.control.ListBox
        EventLabel_2                    matlab.ui.control.Label
        DetrendPanel                    matlab.ui.container.Panel
        ClearPointsButton               matlab.ui.control.Button
        SetReferencePointButton         matlab.ui.control.Button
        text64                          matlab.ui.control.Label
        text63                          matlab.ui.control.Label
        ReferencePointMuValue           matlab.ui.control.EditField
        ReferencePointSlipValue         matlab.ui.control.EditField
        text55                          matlab.ui.control.Label
        SetPoint2Button                 matlab.ui.control.Button
        text50                          matlab.ui.control.Label
        text49                          matlab.ui.control.Label
        text48                          matlab.ui.control.Label
        text47                          matlab.ui.control.Label
        FitTrendButton                  matlab.ui.control.Button
        SetPoint1Button                 matlab.ui.control.Button
        Point2MuValue                   matlab.ui.control.EditField
        Point1MuValue                   matlab.ui.control.EditField
        Point2SlipValue                 matlab.ui.control.EditField
        latex_Slope                     matlab.ui.control.Label
        SlopeValue                      matlab.ui.control.EditField
        DetrendButton_2                 matlab.ui.control.Button
        Point1SlipValue                 matlab.ui.control.EditField
        SaveTableButton_2               matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        AddtoTableButton                matlab.ui.control.Button
        LoadTableButton                 matlab.ui.control.Button
        DetrendedAxes                   matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        IndividualFits                  matlab.ui.container.Tab
        DownsampleFactorEditField       matlab.ui.control.NumericEditField
        DownsampleFactorLabel           matlab.ui.control.Label
        DownsampleButton                matlab.ui.control.StateButton
        UnLoadingStiffness1mEditField   matlab.ui.control.EditField
        UnLoadingStiffness1mEditFieldLabel  matlab.ui.control.Label
        LoadingStiffness1mEditField     matlab.ui.control.EditField
        LoadingStiffness1mEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SaveFileNameEditField_2         matlab.ui.control.EditField
        FileNameLabel_3                 matlab.ui.control.Label
        SlipCheckBox                    matlab.ui.control.CheckBox
        SlipFitParametersLabel          matlab.ui.control.Label
        AgingCheckBox                   matlab.ui.control.CheckBox
        SlipFittingPanel                matlab.ui.container.Panel
        SlipTwoStateGuessButton         matlab.ui.control.StateButton
        text53_5                        matlab.ui.control.Label
        text52_5                        matlab.ui.control.Label
        Guess_dc2Value_s2               matlab.ui.control.EditField
        Guess_b2Value_s2                matlab.ui.control.EditField
        Guess_kValue_s2                 matlab.ui.control.EditField
        Guess_dcValue_s2                matlab.ui.control.EditField
        Guess_bValue_s2                 matlab.ui.control.EditField
        Guess_aValue_s2                 matlab.ui.control.EditField
        Guess_muValue_s2                matlab.ui.control.EditField
        k1mLabel_2                      matlab.ui.control.Label
        dcLabel_2                       matlab.ui.control.Label
        b1Label_2                       matlab.ui.control.Label
        aLabel_2                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Guess_kValue_s1                 matlab.ui.control.EditField
        Guess_dcValue_s1                matlab.ui.control.EditField
        Guess_bValue_s1                 matlab.ui.control.EditField
        Guess_aValue_s1                 matlab.ui.control.EditField
        Guess_muValue_s1                matlab.ui.control.EditField
        ParameterGuesses_5              matlab.ui.control.Label
        AgingFittingPanel               matlab.ui.container.Panel
        AgingTwoStateGuessButton        matlab.ui.control.StateButton
        Guess_dc2Value_a2               matlab.ui.control.EditField
        Guess_b2Value_a2                matlab.ui.control.EditField
        Guess_kValue_a2                 matlab.ui.control.EditField
        Guess_dcValue_a2                matlab.ui.control.EditField
        Guess_bValue_a2                 matlab.ui.control.EditField
        Guess_aValue_a2                 matlab.ui.control.EditField
        Guess_muValue_a2                matlab.ui.control.EditField
        text53_4                        matlab.ui.control.Label
        text52_4                        matlab.ui.control.Label
        k1mLabel                        matlab.ui.control.Label
        dcLabel                         matlab.ui.control.Label
        b1Label                         matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Guess_kValue_a1                 matlab.ui.control.EditField
        Guess_dcValue_a1                matlab.ui.control.EditField
        Guess_bValue_a1                 matlab.ui.control.EditField
        Guess_aValue_a1                 matlab.ui.control.EditField
        Guess_muValue_a1                matlab.ui.control.EditField
        ParameterGuesses                matlab.ui.control.Label
        AgingFitParametersLabel         matlab.ui.control.Label
        LoadTableButton_2               matlab.ui.control.Button
        AddFitToTableButton             matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        FitStepButton                   matlab.ui.control.Button
        SaveFitsButton                  matlab.ui.control.Button
        EventListBox                    matlab.ui.control.ListBox
        EventLabel                      matlab.ui.control.Label
        WeightPanel                     matlab.ui.container.Panel
        WeightExponent                  matlab.ui.control.EditField
        text65                          matlab.ui.control.Label
        WeightFrictionValue             matlab.ui.control.EditField
        WeightLocButton                 matlab.ui.control.Button
        WeightSlipValue                 matlab.ui.control.EditField
        text61                          matlab.ui.control.Label
        text60                          matlab.ui.control.Label
        UITable_Fit                     matlab.ui.control.Table
        EventFileNameEditField_4        matlab.ui.control.EditField
        FileNameLabel_6                 matlab.ui.control.Label
        FittingOptions                  matlab.ui.container.ButtonGroup
        StiffnessButton                 matlab.ui.control.StateButton
        MuButton                        matlab.ui.control.StateButton
        WeightButton                    matlab.ui.control.StateButton
        text67                          matlab.ui.control.Label
        LocNormStress                   matlab.ui.control.EditField
        ClearTestLines                  matlab.ui.control.Button
        WeightAxes                      matlab.ui.control.UIAxes
        FittingAxes                     matlab.ui.control.UIAxes
        GlobalFit                       matlab.ui.container.Tab
        text55_5                        matlab.ui.control.Label
        TestButton_Global               matlab.ui.control.StateButton
        text55_4                        matlab.ui.control.Label
        TypeofEventstoIncludePanel      matlab.ui.container.Panel
        SHS_Button                      matlab.ui.control.StateButton
        VS_Down_Button                  matlab.ui.control.StateButton
        VS_Up_Button                    matlab.ui.control.StateButton
        TabGroup2                       matlab.ui.container.TabGroup
        FullExperimentTab               matlab.ui.container.Tab
        UIAxes_FullExperiment           matlab.ui.control.UIAxes
        SingleEventTab                  matlab.ui.container.Tab
        UIAxes_SingleStep               matlab.ui.control.UIAxes
        SaveFileNameEditField_3         matlab.ui.control.EditField
        FileNameLabel_4                 matlab.ui.control.Label
        SaveFitButton                   matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        SingleEventDropDownLabel        matlab.ui.control.Label
        AcceptFit                       matlab.ui.control.Button
        ClearTestLines_3                matlab.ui.control.Button
        SlipCheckBox_Global             matlab.ui.control.CheckBox
        SlipFitParametersLabel_2        matlab.ui.control.Label
        AgingCheckBox_Global            matlab.ui.control.CheckBox
        SlipFittingPanel_Global         matlab.ui.container.Panel
        SlipTwoStateGuessButton_Global  matlab.ui.control.StateButton
        text53_7                        matlab.ui.control.Label
        text52_7                        matlab.ui.control.Label
        Guess_dc2Value_s2_Global        matlab.ui.control.EditField
        Guess_b2Value_s2_Global         matlab.ui.control.EditField
        Guess_kValue_s2_Global          matlab.ui.control.EditField
        Guess_dcValue_s2_Global         matlab.ui.control.EditField
        Guess_bValue_s2_Global          matlab.ui.control.EditField
        Guess_aValue_s2_Global          matlab.ui.control.EditField
        k1mLabel_4                      matlab.ui.control.Label
        dcLabel_4                       matlab.ui.control.Label
        b1Label_4                       matlab.ui.control.Label
        aLabel_4                        matlab.ui.control.Label
        Guess_kValue_s1_Global          matlab.ui.control.EditField
        Guess_dcValue_s1_Global         matlab.ui.control.EditField
        Guess_bValue_s1_Global          matlab.ui.control.EditField
        Guess_aValue_s1_Global          matlab.ui.control.EditField
        ParameterGuesses_7              matlab.ui.control.Label
        AgingFittingPanel_Global        matlab.ui.container.Panel
        AgingTwoStateGuessButton_Global  matlab.ui.control.StateButton
        Guess_dc2Value_a2_Global        matlab.ui.control.EditField
        Guess_b2Value_a2_Global         matlab.ui.control.EditField
        Guess_kValue_a2_Global          matlab.ui.control.EditField
        Guess_dcValue_a2_Global         matlab.ui.control.EditField
        Guess_bValue_a2_Global          matlab.ui.control.EditField
        Guess_aValue_a2_Global          matlab.ui.control.EditField
        text53_6                        matlab.ui.control.Label
        text52_6                        matlab.ui.control.Label
        k1mLabel_Global                 matlab.ui.control.Label
        dcLabel_Global                  matlab.ui.control.Label
        b1Label_Global                  matlab.ui.control.Label
        aLabel_Global                   matlab.ui.control.Label
        Guess_kValue_a1_Global          matlab.ui.control.EditField
        Guess_dcValue_a1_Global         matlab.ui.control.EditField
        Guess_bValue_a1_Global          matlab.ui.control.EditField
        Guess_aValue_a1_Global          matlab.ui.control.EditField
        ParameterGuesses_6              matlab.ui.control.Label
        AgingFitParametersLabel_2       matlab.ui.control.Label
        LoadingStiffness1mEditField_2   matlab.ui.control.EditField
        UnLoadingStiffness1mEditField_2  matlab.ui.control.EditField
        UnLoadingStiffness1mEditFieldLabel_2  matlab.ui.control.Label
        LoadingStiffness1mEditFieldLabel_2  matlab.ui.control.Label
        FitButton_Global                matlab.ui.control.Button
        EventFileNameEditField_Global   matlab.ui.control.EditField
        FileNameLabel_5                 matlab.ui.control.Label
        LoadTableButton_Global          matlab.ui.control.Button
        SingleEventDropDown             matlab.ui.control.DropDown
        UITable_GlobalFit               matlab.ui.control.Table
        FittingOptions_2                matlab.ui.container.ButtonGroup
        StiffnessButton_2               matlab.ui.control.StateButton
        MuButton_2                      matlab.ui.control.StateButton
        text67_2                        matlab.ui.control.Label
        LocNormStress_2                 matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Data 
        Data_forFit
        ExportData
        Event
        StepNumber
        ExportUITable% Description
        PreUnloadIndex
        PostLoadStartIndex
        LoadingStiffness
        UnloadStiffness
        AgingLawFitData
        AgingLawFitData_2State
        SlipLawFitData
        SlipLawFitData_2State
        WeightParameters
    end
    
    methods (Access = private)
        
        function [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, Type, Num_States)
            switch Type
                case 'Aging'
                if Num_States == 1
                    x_0(1) = str2double(app.Guess_muValue_a1.Value);
                    x_0(2) = str2double(app.Guess_aValue_a1.Value);
                    x_0(3) = str2double(app.Guess_bValue_a1.Value);
                    x_0(4) = str2double(app.Guess_dcValue_a1.Value);
                    x_0(5) = str2double(app.Guess_kValue_a1.Value);
                    x_0(6) = NaN;
                    x_0(7) = NaN;
                elseif Num_States == 2
                    x_0(1) = str2double(app.Guess_muValue_a2.Value);
                    x_0(2) = str2double(app.Guess_aValue_a2.Value);
                    x_0(3) = str2double(app.Guess_bValue_a2.Value);
                    x_0(6) = str2double(app.Guess_b2Value_a2.Value);
                    x_0(4) = str2double(app.Guess_dcValue_a2.Value);
                    x_0(7) = str2double(app.Guess_dc2Value_a2.Value);
                    x_0(5) = str2double(app.Guess_kValue_a2.Value);
                end
                case 'Slip'
                if Num_States == 1
                    x_0(1) = str2double(app.Guess_muValue_s1.Value);
                    x_0(2) = str2double(app.Guess_aValue_s1.Value);
                    x_0(3) = str2double(app.Guess_bValue_s1.Value);
                    x_0(4) = str2double(app.Guess_dcValue_s1.Value);
                    x_0(5) = str2double(app.Guess_kValue_s1.Value);
                    x_0(6) = NaN;
                    x_0(7) = NaN;
                elseif Num_States == 2
                    x_0(1) = str2double(app.Guess_muValue_s2.Value);
                    x_0(2) = str2double(app.Guess_aValue_s2.Value);
                    x_0(3) = str2double(app.Guess_bValue_s2.Value);
                    x_0(6) = str2double(app.Guess_b2Value_s2.Value);
                    x_0(4) = str2double(app.Guess_dcValue_s2.Value);
                    x_0(7) = str2double(app.Guess_dc2Value_s2.Value);
                    x_0(5) = str2double(app.Guess_kValue_s2.Value);
                end
            end
            a = x_0(2);
            b1 = x_0(3);
            d_c1 = x_0(4);
            b2 = x_0(6);
            d_c2 = x_0(7);
            mu_i_test = x_0(1);
            k_i_test = x_0(5);
            StiffnessFlag = ~app.StiffnessButton.Value;
            MuFlag = ~app.MuButton.Value;
            if StiffnessFlag == true && MuFlag == true %fit stiffness and mu
                x_e = [0; 0];
            elseif StiffnessFlag == true && MuFlag == false %fit stiffness, set mu
                x_e = [x_0(1); 0]; 
                x_0(1) = NaN;
            elseif StiffnessFlag == false && MuFlag == true %fit mu, set stiffness
                x_e = [0; x_0(5)];
                x_0(7) = NaN;
            else
                x_e = [x_0(1); x_0(5)]; %set both values
                x_0(1) = NaN;
                x_0(5) = NaN;
            end
            
            %%% Get initial parameters for spring-slider
            v_i = (app.Event(app.StepNumber).EventInfo.V0);
            Slip_ZeroRef = app.Event(app.StepNumber).LoadPointDisplacementData - app.Event(app.StepNumber).LoadPointDisplacementData(1);
            NormStress = round(mean(app.Event(app.StepNumber).NormalStressData), 2, 'significant');
            state1_i = x_0(4)/v_i;
            if Num_States == 2
                state2_i = x_0(7)/v_i;
                mu_i = mu_i_test + a*log(v_i/v_i) +b1*log(v_i*state1_i/d_c1)...
                    + b2*log(v_i*state2_i/d_c2);
            else
                state2_i = 0;
                mu_i = mu_i_test + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
            end
            sigma_i = NormStress;
            tau_i = mu_i*sigma_i;
            slip_i = Slip_ZeroRef(1);
            Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
        end
        
        function TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                        StateLawFlag, StateVarFlag,EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i)
                %%% Aging law test.
                opt = odeset('RelTol',1e-12,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
                if EventFlag
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfStep,...
                        StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                        Time_ZeroRef,Vars_i,opt);
    
                    %%% Compute the load point slip.
                            Slip_f = nan(numel(Time_ZeroRef), 1);
                            [~, kk1] = min(abs(Time_ZeroRef - TimeOfStep));
                            Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                            Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Time_ZeroRef(kk1:end)));
                else
                    HoldTime = Holds{1};
                    TimeOfHold = Holds{2};
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfHold,...
                        StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,HoldTime), Time_ZeroRef,Vars_i,opt);
                %%% Compute the load point slip.
                    Slip_f = nan(numel(Time_ZeroRef), 1);
                    [~, kk1] = min(abs(Time_ZeroRef - TimeOfHold)); %index of time of hold
                    [~, kk2] = min(abs(Time_ZeroRef - (TimeOfHold + HoldTime))); %index of hold time
                    Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                    Slip_f(kk1+1:kk2) = Slip_f(kk1);
                    Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Time_ZeroRef(kk2:end)));
                end    
                bestY = deval(ODE_Sol, Time_ZeroRef);
                Mu_f = bestY(4,:)';
                if EventFlag
                    xdata = Slip_f;
                else
                    xdata = Time_ZeroRef;
                end

                %%% Plot the test over the detrended data in the fitting axes.
                if StateLawFlag
                    if StateVarFlag
                        plot(app.FittingAxes, xdata, Mu_f, 'Color', [0.6350 0.0780 0.1840], 'LineWidth',...
                            2, 'LineStyle', '--', 'DisplayName','Aging Test 2 State');
                        %setappdata(app.TestButton, 'Plot_ATest_2', PAgingTest);
                    else
                        plot(app.FittingAxes,xdata, Mu_f, 'r', 'LineWidth',...
                        2, 'LineStyle', '--', 'DisplayName','Aging Test');
                        %setappdata(app.TestButton, 'Plot_ATest', PAgingTest);
                    end
                else
                    if StateVarFlag
                        plot(app.FittingAxes, xdata, Mu_f, 'Color', [0.3010 0.7450 0.9330],...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName', 'Slip Test 2 State');
                    else
                        plot(app.FittingAxes, xdata, Mu_f, 'c',...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName', 'Slip Test');
                    end
                end
        end


        function dVarsdt = SpringBlock(app, time, Vars, x, x_e, v_i, v_f, TimeOfStep, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, varargin)
            %%% This function contains the governing equations for a spring slider with
            %%% stiffness k. They are cast as coupled ODEs in state variable, friction
            %%% coefficient, and slider velocity. There is a fourth ode for slip that
            %%% integrates the velocity values.
            
            %%% RAS parameters.

                a = x(2);
                b1 = x(3);
                d_c1 = x(4);
                b2 = x(6);
                d_c2 = x(7);
                if StiffnessFlag == 0 %constraining stiffness
                    k = x_e(2);
                else % fitting stiffness
                    k = x(5);
                end
            
            %%% Simulation variables.
                state1 = Vars(1,1);
                state2 = Vars(2,1);
                vel = Vars(3,1);
                mu = Vars(4,1);
                sigma = Vars(6,1);
            
            %%% Check if a hold time is provided.
                if isscalar(varargin)
                    Holds = varargin;
                    HoldTime = Holds{1};
                end
            
            %%% Determine which type of event to fit.
                if EventFlag
            %%% Fit a velcity step.
                    if time < TimeOfStep
                        v_L = v_i;
                    else
                        v_L = v_f;
                    end
                else
            %%% Fit a slide-hold-slide
                    if time < TimeOfStep
                        v_L = v_i;
                    elseif time >= TimeOfStep && time < TimeOfStep + HoldTime
                        v_L = 0;
                    else
                        v_L = v_f;
                    end
                end
            
            %%% Friction stress.
                %v_L
                dmudt = k*(v_L - vel);
            %%% Shear stress
                dtaudt = dmudt*sigma;
            %%% Normal stress
                dsigmadt = 0;
            
            %%% State evolution.
                if StateLawFlag
            %%% Aging Law
                    dstate1dt = 1 - state1*vel/d_c1;
                    if StateVarFlag == true
                        dstate2dt = 1 - state2*vel/d_c2;
                    else
                        dstate2dt = 0;
                    end
                else
            %%% Slip Law
                    dstate1dt = -(vel*state1/d_c1)*log(vel*state1/d_c1);
                    if StateVarFlag == true
                        dstate2dt = -(vel.*state2/d_c2).*log(vel.*state2./d_c2);
                    else
                        dstate2dt = 0;
                    end
                end
            
            %%% Velocity
                if StateVarFlag == true
                    dveldt = (vel/a)*(dmudt - b1*dstate1dt./state1...
                        - b2*dstate2dt/state2);
                else
                    dveldt = (vel/a)*(dmudt - b1*dstate1dt/state1);
                end
            %%% Sample Displacement
                dslipdt = vel;
            
            %%% Create ode vector.
                dVarsdt = [dstate1dt; dstate2dt; dveldt; dmudt; dslipdt; dsigmadt;...
                    dtaudt];
        end


        function [x_0, x_f, State1_f, State2_f, Vel_f, Mu_f, Slip_f, SampleSlip_f, exitflag, ODE_Sol, residual, jacobian, resnorm] = RASFittingLD1(app, x_0, x_e, v_i, v_f, NormStress, TimeOfStep, Mu_Data, LoadPointSlip, Exp_Time, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, OutputFlag, varargin)
            
            %%% Description of option flags.
            %%%     StateLawFlag = true : use the aging law
            %%%     StateLawFlag = false : use the slip law
            %%%     StateVarFlag = true :  use two state variables
            %%%     StateVarFlag = false :  use ones state variable
            %%%     EventFlag = true : fitting a velocity step
            %%%     EventFlag = false : fitting a slide-hold-slide
            
            %%% Contents of parameter array 'x_0'
            %%%     x(1) = mu_0
            %%%     x(2) = a;
            %%%     x(3) = b1;
            %%%     x(4) = d_c1;
            %%%     x(5) = k;
            %%%     x(6) = b2;
            %%%     x(7) = d_c2;
            %%%     x(8) = alpha;
            
            %%% Time steps used for performing fitting simulations and optimization.
                tSpan = linspace(0,Exp_Time(end),1e3)';
            %%% Optimize using nonlinear least squares fitting.
            %%% Experimental data that we are fitting.
                Data_test = [Mu_Data'; LoadPointSlip'];
            
            %%% Set up objective function where the actual least squares minimization
            %%% occurs, see below for description.
            %%% Check if a hold time is provided.
            if numel(varargin) == 1
                Holds = varargin;
                HoldTime = Holds{1};
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data_test, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, SampleSlipFlag, OutputFlag, HoldTime);
            else
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data_test, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, OutputFlag, SampleSlipFlag);
            end
            %%% Options for lsqnonlin.
            options = optimoptions(@lsqnonlin,'Display','iter-detailed','Algorithm',...
                'levenberg-marquardt');
            %%% Carry out the optimization.
            [x_f, resnorm, residual, exitflag, ~, ~, jacobian] = ...
                    lsqnonlin(myObjective, x_0, [], [], options);
            
            %%% Run a spring-slider simulation using the fitted parameters contained in
            %%% x_f. This will produce numerical friion and slip data that can be
            %%% plotted over the experimental data.
            a = x_f(2);
            b1 = x_f(3);
            d_c1 = x_f(4);
            b2 = x_f(6);
            d_c2 = x_f(7);
            if MuFlag == true
                mu_0 = x_f(1);
            else
                mu_0 = x_e(1);
            end
            
            state1_i = d_c1/v_i;
            if StateVarFlag
                state2_i = d_c2/v_i;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                    + b2*log(v_i*state2_i/d_c2);
            else
                state2_i = 0;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
            end
            sigma_i = NormStress;
            tau_i = mu_i*sigma_i;
            slip_i = LoadPointSlip(1);
            Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            opt = odeset('RelTol',1e-9,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            if EventFlag
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    tSpan,Vars_i,opt);
            else
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,HoldTime), tSpan,Vars_i,opt);
            end
            
            %%% Evaluate the ode solution for the experimental time steps.
            bestY = deval(ODE_Sol, Exp_Time);
            State1_f = bestY(1,:)';
            State2_f = bestY(2,:)';
            Vel_f = bestY(3,:)';
            Mu_f = bestY(4,:)';
            SampleSlip_f = bestY(5,:)';
            
            %%% Compute the load point slip
            Slip_f = nan(numel(Exp_Time), 1);
            [~, kk1] = min(abs(Exp_Time - TimeOfStep));
            Slip_f(1:kk1) = v_i*Exp_Time(1:kk1);
            if EventFlag
                Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Exp_Time(kk1:end)));
            else
                [~, kk2] = min(abs(Exp_Time - (TimeOfStep + HoldTime)));
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Exp_Time(kk2:end)));
            end
        end

        
               %    %[x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
            %    %    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
            %    %    varargout] = GetStuff(app, handles);
        
        function [Weight, WeightInfo, Slip_ZeroRef] = WeightCalc(app) %Unchanged from RSFit3000
                Slip_ZeroRef = app.Event(app.StepNumber).LoadPointDisplacementData - app.Event(app.StepNumber).LoadPointDisplacementData(1);
                N = numel(Slip_ZeroRef);
                Weight = ones(1, N);
                slip_weight = app.WeightSlipValue.Value;
                SlipWeight = str2double(slip_weight);
                [~, i_w] = min(abs(Slip_ZeroRef - SlipWeight));
            %%% Calculate weight function.
                p_String =  app.WeightExponent.Value;
                p = str2double(p_String);
                Weight(1, i_w:end) = (N./(1:N-i_w+1)).^p;
            %%% Information for weight function.
                WeightInfo = [N; i_w; p];
            %%% Store
                app.WeightParameters = struct('Weights', Weight, 'Weight_Info', [N; i_w; p]);
        end
        
        function FitError = objFcn(app, x, x_e, ExpTime, Data, tSpan, v_i, v_f, NormStress, TimeOfStep, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, OutputFlag, varargin)
            %%% This is the optimization function that is used to determine the best
            %%% fit values for the RAS parameters contained in the vector x. It runs a
            %%% spring-slider simulation using the values in x, and attempts to
            %%% minimize the difference between the simulated friction coefficient and
            %%% slip values and the exerimental data.
                a = x(2);
                b1 = x(3);
                d_c1 = x(4);
                b2 = x(6);
                d_c2 = x(7);
                if StiffnessFlag == true && MuFlag == true
                    mu_0 = x(1);
                    k = x(5);
                elseif StiffnessFlag == true && MuFlag == false
                    mu_0 = x_e(1);
                    k = x(5);
                elseif StiffnessFlag == false && MuFlag == true
                    mu_0 = x(1);
                    k = x_e(2);
                else
                    mu_0 = x_e(1);
                    k = x_e(2);
                end
                if OutputFlag
                    disp(['mu_0 = ',num2str(mu_0),' ','a = ',num2str(a),' ','b = ',...
                      num2str(b1),' ','d_c = ',num2str(d_c1),' ','k = ',num2str(k)])
                end
                
            %%% Initial, steady-state conditions for the section of the experiment
            %%% being analyzed.
                state1_i = d_c1/v_i;
                if StateVarFlag
                    state2_i = d_c2/v_i;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                        + b2*log(v_i*state2_i/d_c2);
                else
                    state2_i = 0;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
                end
                LoadPointSlip = Data(2,:);
                slip_i = LoadPointSlip(1);
                sigma_i = NormStress;
                tau_i = mu_i*sigma_i;
                Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            %%% Run the spring-slider simulation.
                opt = odeset('RelTol',1e-12,'AbsTol',...
                    [1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            %%% Check if a hold time is provided.
                if numel(varargin) == 1
                    HoldTime = varargin{1};
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,HoldTime), tSpan,Vars_i,opt);
                else
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag), tSpan,Vars_i,opt);
                end
                if ODE_Sol.x(end) < ExpTime(end)
                    if b2 == 0
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, d_c1 = %.2f, k = %f'],...
                            mu_0, a, b1, d_c1, k);
                    else
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, b_2 = %f, d_c1 = %.2f, d_c2 = %.2f, k = %f'],...
                            mu_0, a, b1, b2, d_c1, d_c2, k);
                    end
                    throw(ME)
            %%% Evaluate the solution structure at the experimental time values.
                else
                    simY = deval(ODE_Sol, ExpTime);
                end
            
            %%% Fit based on minimizing friction coefficient.
                FitError = simY(4,:) - Data(1,:);
                FitError = Weight.*FitError;
        end
        
        
        function [x_0, x_f, EventError,Slip_f, Mu_f, CovarA, FrictionCOD_Event, exitflag_f, GIC, logL] = FitEvent(app, x_0,x_e, Vars_i, v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                Slip_ZeroRef,Time_ZeroRef, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag,MuFlag,Weight,SampleSlipFlag, FrictionVariability, Holds)
            OutputFlag = 1;
            if StateLawFlag == 1
                StateLaw = 'Aging';
                FitColor = 'r';
                Legend_Name = 'Aging Law Fit';
                num_params = 5;
                if StateVarFlag == 1
                    FitColor = '#ffa07a';
                    Legend_Name = 'Aging Law Fit 2S';
                    num_params = 7;
                end
            else
                StateLaw = 'Slip';
                FitColor = 'b';
                Legend_Name = 'Slip Law Fit';
                num_params = 5;
                if StateVarFlag == 1
                    FitColor = "#80B3FF";
                    Legend_Name = 'Slip Law Fit 2S';
                    num_params = 7;
                end
            end

            if EventFlag == true %VS
                try
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflag_f,~,residual_f,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag, OutputFlag);
                catch ME
                    disp(ME.message)
                    msg = 'ODE solver failure';
                    error(msg);
                end
            else %SHS
                try 
                    HoldTime = Holds{1};
                    TimeOfHold = Holds{2};
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflag_f,~,residual_f,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfHold,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag, OutputFlag, HoldTime);
                catch ME
                    disp(ME.message)
                    msg = 'ODE solver failure';
                    error(msg);
                end
            end%
        
            %%% Compute the R-squared value
            res = (Mu_f - Friction_Detrend);
            n = length(Mu_f);
            FrictionResidual = sum((res.^2));
            sigma2 = FrictionResidual/n;
            logL = -n/2 * log(2*pi*sigma2) - (FrictionResidual/(2*sigma2)); %can change this exponent if you want to weight the fit more
            FrictionCOD_Event = 1 - FrictionResidual/FrictionVariability;
            
            %set(handles.AgingRes, 'String', num2str(FrictionCOD_A));
        
            %%% Compute standard deviation. Two-sigma error.
            if StateVarFlag
                x_fA = x_f;
                if StiffnessFlag == true & MuFlag == true
                    N = numel(Time_ZeroRef) - 7;
                    jacA = jacobian;
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    mu_0Error = ErrorsA(1,1);
                    aError = ErrorsA(2,1);
                    b1Error = ErrorsA(3,1);
                    d_c1Error = ErrorsA(4,1);
                    kError = ErrorsA(5,1);
                    b2Error = ErrorsA(6,1);
                    d_c2Error = ErrorsA(7,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                        + 2*CovarA(3,6) - 2*CovarA(2,3) - 2*CovarA(2,6));
                elseif StiffnessFlag == false & MuFlag == true
                    N = numel(Time_ZeroRef) - 6;
                    jacA = [jacobian(:,1:4), jacobian(:,6:7)];
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    mu_0Error = ErrorsA(1,1);
                    aError = ErrorsA(2,1);
                    b1Error = ErrorsA(3,1);
                    d_c1Error = ErrorsA(4,1);
                    b2Error = ErrorsA(5,1);
                    d_c2Error = ErrorsA(6,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                        + 2*CovarA(3,5) - 2*CovarA(2,3) - 2*CovarA(2,5));
                elseif StiffnessFlag == true & MuFlag == false
                    N = numel(Time_ZeroRef) - 6;
                    jacA = jacobian(:,2:7);
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    aError = ErrorsA(1,1);
                    b1Error = ErrorsA(2,1);
                    d_c1Error = ErrorsA(3,1);
                    kError = ErrorsA(4,1);
                    b2Error = ErrorsA(5,1);
                    d_c2Error = ErrorsA(6,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                        + 2*CovarA(2,5) - 2*CovarA(1,2) - 2*CovarA(1,5));
                else
                    N = numel(Time_ZeroRef) - 5;
                    jacA = [jacobian(:,2:4), jacobian(:,6:7)];
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    aError = ErrorsA(1,1);
                    b1Error = ErrorsA(2,1);
                    d_c1Error = ErrorsA(3,1);
                    b2Error = ErrorsA(4,1);
                    d_c2Error = ErrorsA(5,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                        + 2*CovarA(2,5) - 2*CovarA(1,2) - 2*CovarA(1,4));
                end
                b2String = [strrep(sprintf('%0.2e ', x_fA(6)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', b2Error), 'e-0', 'e-')];
                d_c2String = [sprintf('%0.3f ', x_fA(7)), char(177),...
                    sprintf(' %0.3f', d_c2Error)];
%                set(handles.AgingFit_b2Value, 'String', b2String);
%                set(handles.AgingFit_dc2Value, 'String', d_c2String);
            else
                x_fA = x_f(1:5);
                if StiffnessFlag == true & MuFlag == true
                    N = numel(Time_ZeroRef) - 5;
                    jacA = jacobian(:,1:5);
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    mu_0Error = ErrorsA(1,1);
                    aError = ErrorsA(2,1);
                    b1Error = ErrorsA(3,1);
                    d_c1Error = ErrorsA(4,1);
                    kError = ErrorsA(5,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(2,3));
                elseif StiffnessFlag == false & MuFlag == true
                    N = numel(Time_ZeroRef) - 4;
                    jacA = jacobian(:,1:4);
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    mu_0Error = ErrorsA(1,1);
                    aError = ErrorsA(2,1);
                    b1Error = ErrorsA(3,1);
                    d_c1Error = ErrorsA(4,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(2,3));
                elseif StiffnessFlag == true & MuFlag == false
                    N = numel(Time_ZeroRef) - 4;
                    jacA = jacobian(:,2:5);
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    aError = ErrorsA(1,1);
                    b1Error = ErrorsA(2,1);
                    d_c1Error = ErrorsA(3,1);
                    kError = ErrorsA(4,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(1,2));
                else
                    N = numel(Time_ZeroRef) - 3;
                    jacA = jacobian(:,2:4);
                    CovarA = resnorm*(inv(jacA'*jacA))/N;
                    ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                    aError = ErrorsA(1,1);
                    b1Error = ErrorsA(2,1);
                    d_c1Error = ErrorsA(3,1);
                    aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(1,2));
                end
            end
        
            %%%Format the numbers for main parameters to print in the gui.
            if StiffnessFlag == true
                kString = [strrep(sprintf('%0.2e ', x_fA(5)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', kError), 'e-0', 'e-')];
                %set(handles.AgingFit_kValue, 'String', kString);
            else
                x_f(5,1) = x_e(2,1);
                kError = 0;
                num_params = num_params - 1;
            end
            if MuFlag == true
                mu_0String = [strrep(sprintf('%0.2e ', x_fA(1)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', mu_0Error), 'e-0', 'e-')];
                %set(handles.AgingFit_muValue, 'String', mu_0String);
            else
                x_f(1,1) = x_e(1,1);
                mu_0Error = 0;
                num_params = num_params - 1;
            end
            aString = [strrep(sprintf('%0.2e ', x_fA(2)), 'e-0', 'e-'), char(177),...
                strrep(sprintf(' %0.2e', aError), 'e-0', 'e-')];
            b1String = [strrep(sprintf('%0.2e ', x_fA(3)), 'e-0', 'e-'), char(177),...
                strrep(sprintf(' %0.2e', b1Error), 'e-0', 'e-')];
            d_c1String = [sprintf('%0.3f ', x_fA(4)), char(177),...
                sprintf(' %0.3f', d_c1Error)];

            %%% Display the fitted parameters in aging law fields.
            %set(handles.AgingFit_aValue, 'String', aString);
            %set(handles.AgingFit_bValue, 'String', b1String);
            %set(handles.AgingFit_dcValue, 'String', d_c1String);

            %%% Plot the fit over the detrended data in the fitting axes.
            hold(app.FittingAxes, 'on');
            if EventFlag
                plot(app.FittingAxes,Slip_f, Mu_f, 'Color', FitColor, 'LineWidth', 2, 'DisplayName', Legend_Name);
            else
                plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'Color', FitColor, 'LineWidth', 2, 'DisplayName', Legend_Name);
            end
            hold(app.FittingAxes, 'off');
            %%% Store the fitted parameters and data.
            %setappdata(app.FitButton,'X0',x_0);
            %setappdata(app.FitButton,'XF_Aging',x_f);
            %setappdata(app.FitButton,'SlipFit_Aging',Slip_f);
            %setappdata(app.FitButton,'FrictionFit_Aging',Mu_f);
            %setappdata(app.FitButton,'CovarianceA',CovarA);
        
            %%% Store the error intervals.
            if StateVarFlag == true
                EventError = [mu_0Error; aError; b1Error; b2Error; d_c1Error;...
                    d_c2Error; kError; aminusbError];
                GIC = num_params*n - 2 * logL; % Calculate GIC 
                %set(app.AgingIC_2, 'Value', num2str(GIC));
            else
                EventError = [mu_0Error; aError; b1Error; 0; d_c1Error;...
                    0; kError; aminusbError];
                GIC = num_params*n - 2 * logL; % Calculate GIC 
                %set(app.AgingIC, 'Value', num2str(GIC));
            end    
        end
        
        function results = GlobalTestFit(app, events_include)
            v_i = (app.Event(event_num).EventInfo.V0);
            v_f = (app.Event(event_num).EventInfo.VF);
            NormStress = app.Event(event_num).NormalStressData(app.Event(event_num).IndexData == app.Event(event_num).EventInfo.StepIndex);
            Slip_ZeroRef = app.Event(event_num).LoadPointDisplacementData - app.Event(event_num).LoadPointDisplacementData(1);
            Time_ZeroRef = app.Event(event_num).TimeData - app.Event(event_num).TimeData(1);
            

            %EventFlag = this is for SHS vs VS
            AgingStateVarFlag = app.AgingTwoStateGuessButton.Value;
            SlipStateVarFlag = app.SlipTwoStateGuessButton.Value;
            StiffnessFlag = ~app.StiffnessButton.Value;
            MuFlag = ~app.MuButton.Value;
            WeightFlag = app.WeightButton.Value;   

            %app.Event(event_num).FittingOptions = struct('EventType', OptionE, 'UseTwoStateVariables',...
            %        OptionS, 'ConstrainMu_0', OptionMu, 'ConstrainStiffness', OptionK,...
            %        'UseWeightFunction', OptionW);
            
            if isnan(app.Event(event_num).EventInfo.HoldTime) %VS
                EventFlag = true;
                Holds{1} = [];
                TimeOfStep = Time_ZeroRef(app.Event(event_num).IndexData == app.Event(event_num).EventInfo.StepIndex);
            else %SHS
                EventFlag = false;
                Holds{1} = app.Event(event_num).EventInfo.HoldTime;
                Holds{2} = Time_ZeroRef(app.Event(event_num).IndexData == app.Event(event_num).EventInfo.StepIndex);
                TimeOfStep = [];
            end
            %%% Check what fits to do.
            AgingLawFlag =  app.AgingCheckBox.Value;
            SlipLawFlag = app.SlipCheckBox.Value;
            legend(app.FittingAxes,'-DynamicLegend');
            hold(app.FittingAxes, 'on');
            if AgingLawFlag == 1
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 1);
                TwoState = false;
                StateLawFlag = 1;
                TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                if AgingStateVarFlag
                    [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 2);
                    TwoState = true;
                    StateLawFlag = 1;
                    TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                end
            end
            if SlipLawFlag == 1
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 1);
                TwoState = false;
                StateLawFlag = 0;
                TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                if SlipStateVarFlag
                    [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 2);
                    TwoState = true;
                    StateLawFlag = 0;
                    TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                end
            end

            hold(app.FittingAxes, 'off');

            %%% Aging law test.
            app.Event
            opt = odeset('RelTol',1e-12,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            if EventFlag
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    Time_ZeroRef,Vars_i,opt);

                %%% Compute the load point slip.
                        Slip_f = nan(numel(Time_ZeroRef), 1);
                        [~, kk1] = min(abs(Time_ZeroRef - TimeOfStep));
                        Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                        Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Time_ZeroRef(kk1:end)));
            else
                HoldTime = Holds{1};
                TimeOfHold = Holds{2};
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfHold,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,HoldTime), Time_ZeroRef,Vars_i,opt);
            %%% Compute the load point slip.
                Slip_f = nan(numel(Time_ZeroRef), 1);
                [~, kk1] = min(abs(Time_ZeroRef - TimeOfHold)); %index of time of hold
                [~, kk2] = min(abs(Time_ZeroRef - (TimeOfHold + HoldTime))); %index of hold time
                Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Time_ZeroRef(kk2:end)));
            end    
            bestY = deval(ODE_Sol, Time_ZeroRef);
            Mu_f = bestY(4,:)';
            if EventFlag
                xdata = Slip_f;
            else
                xdata = Time_ZeroRef;
            end
        end
        
        
        function TotalFitError = GlobalObjFcn(app, x, x_e,  ExpTime, events_included, Event, FitErrors, StateLawFlag, StateVarFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, varargin)
            FitErrors = [];
            parfor i = [1:length(events_included)]
                event_num = events_included(i);
            %%% This is the optimization function that is used to determine the best
            %%% fit values for the RAS parameters contained in the vector x. It runs a
            %%% spring-slider simulation using the values in x, and attempts to
            %%% minimize the difference between the simulated friction coefficient and
            %%% slip values and the exerimental data.

            % data that changes
                
                LoadPointSlip = Event(event_num).LoadPointDisplacementData;
                slip_i = LoadPointSlip(1);
                v_i = Event(event_num).EventInfo.V0;
                v_f = Event(event_num).EventInfo.VF;
                tSpan = Event(event_num).TimeData - Event(event_num).TimeData(1);
                TimeOfStep = Event(event_num).TimeData(Event(event_num).IndexData == Event(event_num).EventInfo.StepIndex);
                NormStress = mean(Event(event_num).NormalStressData);
                if isnan(Event(event_num).EventInfo.HoldTime)
                    EventFlag = true; %VS
                else
                    EventFlag = false; %SHS
                end

            %RS parameters don't chage
                a = x(2);
                b1 = x(3);
                d_c1 = x(4);
                b2 = x(6);
                d_c2 = x(7);
                if StiffnessFlag == true && MuFlag == true
                    mu_0 = x(1);
                    k = x(5);
                elseif StiffnessFlag == true && MuFlag == false
                    mu_0 = x_e(1);
                    k = x(5);
                elseif StiffnessFlag == false && MuFlag == true
                    mu_0 = x(1);
                    k = x_e(2);
                else
                    mu_0 = x_e(1);
                    k = x_e(2);
                end
            
                  disp(['mu_0 = ',num2str(mu_0),' ','a = ',num2str(a),' ','b = ',...
                      num2str(b1),' ','d_c = ',num2str(d_c1),' ','k = ',num2str(k)])
            
            %%% Initial, steady-state conditions for the section of the experiment
            %%% being analyzed.
                state1_i = d_c1/v_i;
                if StateVarFlag
                    state2_i = d_c2/v_i;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                        + b2*log(v_i*state2_i/d_c2);
                else
                    state2_i = 0;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
                end
                sigma_i = NormStress;
                tau_i = mu_i*sigma_i;

                Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            %%% Run the spring-slider simulation.
                opt = odeset('RelTol',1e-12,'AbsTol',...
                    [1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            %%% Check if a hold time is provided.
                if numel(varargin) == 1
                    HoldTime = varargin{1};
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,HoldTime), tSpan,Vars_i,opt);
                else
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag), tSpan,Vars_i,opt);
                end
                if ODE_Sol.x(end) < ExpTime(end)
                    if b2 == 0
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, d_c1 = %.2f, k = %f'],...
                            mu_0, a, b1, d_c1, k);
                    else
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, b_2 = %f, d_c1 = %.2f, d_c2 = %.2f, k = %f'],...
                            mu_0, a, b1, b2, d_c1, d_c2, k);
                    end
                    throw(ME)
            %%% Evaluate the solution structure at the experimental time values.
                else
                    simY = deval(ODE_Sol, ExpTime);
                end
            
            %%% Fit based on minimizing friction coefficient.
                %FitErrors(i) = simY(4,:) - Event(event_num).FrictionData;
                %FitErrors(i) = Weight.*FitErrors{event};
            end
            %TotalFitError = FitErrors;
        end
        
        function [x_0, x_f, State1_f, State2_f, Vel_f, Mu_f, Slip_f, SampleSlip_f, exitflag, ODE_Sol, residual, jacobian, resnorm] = Global_RASFittingLD1(app, x_0, x_e, v_i, v_f, NormStress, TimeOfStep, Mu_Data, LoadPointSlip, Exp_Time, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, varargin)
            
            %%% Description of option flags.
            %%%     StateLawFlag = true : use the aging law
            %%%     StateLawFlag = false : use the slip law
            %%%     StateVarFlag = true :  use two state variables
            %%%     StateVarFlag = false :  use ones state variable
            %%%     EventFlag = true : fitting a velocity step
            %%%     EventFlag = false : fitting a slide-hold-slide
            
            %%% Contents of parameter array 'x_0'
            %%%     x(1) = mu_0
            %%%     x(2) = a;
            %%%     x(3) = b1;
            %%%     x(4) = d_c1;
            %%%     x(5) = k;
            %%%     x(6) = b2;
            %%%     x(7) = d_c2;
            %%%     x(8) = alpha;
            
            %%% Time steps used for performing fitting simulations and optimization.
                tSpan = linspace(0,Exp_Time(end),1e3)';
            %%% Optimize using nonlinear least squares fitting.
            %%% Experimental data that we are fitting.
                Data_test = [Mu_Data'; LoadPointSlip'];
            
            %%% Set up objective function where the actual least squares minimization
            %%% occurs, see below for description.
            %%% Check if a hold time is provided.
            if numel(varargin) == 1
                Holds = varargin;
                HoldTime = Holds{1};
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data_test, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, SampleSlipFlag, OutputFlag, HoldTime);
            else
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data_test, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, OutputFlag, SampleSlipFlag);
            end
            %%% Options for lsqnonlin.
            options = optimoptions(@lsqnonlin,'Display','iter-detailed','Algorithm',...
                'levenberg-marquardt');
            %%% Carry out the optimization.
            [x_f, resnorm, residual, exitflag, ~, ~, jacobian] = ...
                    lsqnonlin(myObjective, x_0, [], [], options);
            
            %%% Run a spring-slider simulation using the fitted parameters contained in
            %%% x_f. This will produce numerical friion and slip data that can be
            %%% plotted over the experimental data.
            a = x_f(2);
            b1 = x_f(3);
            d_c1 = x_f(4);
            b2 = x_f(6);
            d_c2 = x_f(7);
            if MuFlag == true
                mu_0 = x_f(1);
            else
                mu_0 = x_e(1);
            end
            
            state1_i = d_c1/v_i;
            if StateVarFlag
                state2_i = d_c2/v_i;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                    + b2*log(v_i*state2_i/d_c2);
            else
                state2_i = 0;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
            end
            sigma_i = NormStress;
            tau_i = mu_i*sigma_i;
            slip_i = LoadPointSlip(1);
            Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            opt = odeset('RelTol',1e-12,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            if EventFlag
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    tSpan,Vars_i,opt);
            else
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,HoldTime), tSpan,Vars_i,opt);
            end
            
            %%% Evaluate the ode solution for the experimental time steps.
            bestY = deval(ODE_Sol, Exp_Time);
            State1_f = bestY(1,:)';
            State2_f = bestY(2,:)';
            Vel_f = bestY(3,:)';
            Mu_f = bestY(4,:)';
            SampleSlip_f = bestY(5,:)';
            
            %%% Compute the load point slip
            Slip_f = nan(numel(Exp_Time), 1);
            [~, kk1] = min(abs(Exp_Time - TimeOfStep));
            Slip_f(1:kk1) = v_i*Exp_Time(1:kk1);
            if EventFlag
                Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Exp_Time(kk1:end)));
            else
                [~, kk2] = min(abs(Exp_Time - (TimeOfStep + HoldTime)));
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Exp_Time(kk2:end)));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
              cell_array{i} = vars(i).name;
            end
            app.TimesDropDown.Items = cell_array;
            app.FrictionDropDown.Items = cell_array;
            app.DisplacementmmDropDown.Items = cell_array;
            app.IndexDropDown.Items = cell_array;
            app.Eff_NormalDropDown.Items = cell_array;
        end

        % Button pushed function: PickVelocityStepButton
        function PickVelocityStepButtonPushed(app, event)
                % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                IofC = dcm_Info.DataIndex;
%                pick_disp = dcm_Info.Position(1);
%                pick_friction = dcm_Info.Position(2);
                datacursormode off

            temp_indices = [1:length(app.Data.Disp)]';

            min_index = min(app.Data.Index(app.Data.Disp>=(app.Data.Disp(IofC)-10) & app.Data.Index > app.PostLoadStartIndex));
            max_index = max(app.Data.Index(app.Data.Disp<=(app.Data.Disp(IofC)+10) & app.Data.Index < app.PreUnloadIndex));

            pick_friction = app.Data.Friction(IofC);
            pick_index = app.Data.Index(IofC);
            pick_time = app.Data.Time(IofC);
            pick_disp = app.Data.Disp(IofC);
            v0_indices = (temp_indices < IofC & app.Data.Disp > app.Data.Disp(IofC) - 100 & app.Data.Index < max_index & app.Data.Index > app.PostLoadStartIndex);
            vf_indices = (app.Data.Disp < app.Data.Disp(IofC)+100 & temp_indices > IofC & app.Data.Index < app.PreUnloadIndex);
            v0 = diff(app.Data.Disp(v0_indices))./diff(app.Data.Time(v0_indices));
            vf = diff(app.Data.Disp(vf_indices))./diff(app.Data.Time(vf_indices));


            app.UIFigure.HandleVisibility = fhv;        % return original state

            plot(app.UIAxes, pick_disp, pick_friction,'ro', 'MarkerFaceColor', 'r') 
            plot(app.UIAxes2, pick_time, pick_disp, 'ro', 'MarkerFaceColor', 'r') 
            ylim(app.UIAxes2, [app.Data.Disp(app.Data.Index == min_index), app.Data.Disp(app.Data.Index == max_index)])
            xlim(app.UIAxes2, [app.Data.Time(app.Data.Index == min_index), app.Data.Time(app.Data.Index == max_index)])
            app.ExportData.pick_time = pick_time;
            app.ExportData.pick_disp = pick_disp;
            app.ExportData.pick_index = pick_index;
            app.ExportData.pick_friction = pick_friction;
            app.ExportData.v0 = round(mean(v0), app.VelocitySigFigEditField.Value, 'significant');
            app.ExportData.vf = round(mean(vf), app.VelocitySigFigEditField.Value, 'significant');
            app.ExportData.HoldTime = nan;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            disp = app.Data.Disp;
            friction = app.Data.Friction;
            plot(app.UIAxes_Stiffness, disp, friction) 
            hold(app.UIAxes_Stiffness,'on');
        end

        % Callback function: TimesDropDown, TimesDropDown
        function TimesDropDownValueChanged(app, event)
            value = app.TimesDropDown.Value;
            app.Data.Time = evalin('base', value);
        end

        % Callback function: FrictionDropDown, FrictionDropDown
        function FrictionDropDownValueChanged(app, event)
            value = app.FrictionDropDown.Value;
            app.Data.Friction = evalin('base', value);
        end

        % Callback function: DisplacementmmDropDown, DisplacementmmDropDown
        function DisplacementmmDropDownOpening(app, event)
            value = app.DisplacementmmDropDown.Value;
            app.Data.Disp = evalin('base', value);
        end

        % Callback function: IndexDropDown, IndexDropDown
        function IndexDropDownValueChanged(app, event)
            value = app.IndexDropDown.Value;
            app.Data.Index = evalin('base', value);
        end

        % Button pushed function: AcceptPickButton
        function AcceptPickButtonPushed(app, event)
            app.UITable.Data(end+1,:) = [app.ExportData.pick_disp, app.ExportData.pick_friction, app.ExportData.v0, app.ExportData.vf, app.ExportData.pick_index, app.ExportData.HoldTime];
            app.UITable.Data = sortrows(app.UITable.Data, 5);

            cla(app.UIAxes)
            cla(app.UIAxes2)
            %hold(app.UIAxes,'on');
            plot(app.UIAxes, app.Data.Disp(app.Data.Index >= app.PostLoadStartIndex & app.Data.Index <= app.PreUnloadIndex), app.Data.Friction(app.Data.Index >= app.PostLoadStartIndex & app.Data.Index <= app.PreUnloadIndex),'.')
            hold(app.UIAxes,'on');
            plot(app.UIAxes, app.Data.Disp, app.Data.Friction)
            
            plot(app.UIAxes2, app.Data.Time, app.Data.Disp)
            
            hold(app.UIAxes,'on');
            hold(app.UIAxes2,'on');
            ylim(app.UIAxes2, [min(app.Data.Disp),max(app.Data.Disp)])
            xlim(app.UIAxes2, [min(app.Data.Time),max(app.Data.Time)])
            ylim(app.UIAxes, [0,max(app.Data.Friction)+0.1])
            xlim(app.UIAxes, [min(app.Data.Disp),max(app.Data.Disp)])

            plot(app.UIAxes, app.UITable.Data(:,1), app.UITable.Data(:,2),'ro', 'MarkerFaceColor', 'r')
            plot(app.UIAxes2, app.Data.Time(ismember(app.Data.Index, app.UITable.Data(:,5))),app.Data.Disp(ismember(app.Data.Index, app.UITable.Data(:,5))), 'ro', 'MarkerFaceColor', 'r') 
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            export_UI_Table = app.UITable.Data;
            disp = app.Data.Disp;
            index = app.Data.Index;
            time = app.Data.Time;
            friction = app.Data.Friction;
            eff_normal = app.Data.Eff_Normal;
            LoadingStiffness = app.LoadingStiffness;
            UnloadingStiffness = app.UnloadStiffness;
            PostLoadingIndex = app.PostLoadStartIndex;
            PreUnloadIndex = app.PreUnloadIndex;

            save(app.EventFileNameEditField_2.Value + ".mat", "export_UI_Table", "disp", "time", "friction", "eff_normal", "index", "UnloadingStiffness", "LoadingStiffness", "PostLoadingIndex", "PreUnloadIndex")
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            try
                load(app.EventFileNameEditField.Value + ".mat", "export_UI_Table");
                export_UI_Table = export_UI_Table; % this needs to be here to throw an error
            catch 
                load(app.EventFileNameEditField.Value + ".mat", "export_UI_Table_2");
                export_UI_Table = export_UI_Table_2;
            end
            app.EventListBox_Detrend.Items = "Event " + [1:height(export_UI_Table)];
            load(app.EventFileNameEditField.Value + ".mat", "time", "disp", "friction", "eff_normal", "index", "LoadingStiffness", "UnloadingStiffness", "PostLoadingIndex", "PreUnloadIndex")
            app.Data.Time = time;
            app.Data.Disp = disp;
            app.Data.Friction = friction;
            app.Data.Eff_Normal = eff_normal;
            app.Data.Index = index;
            app.LoadingStiffness = LoadingStiffness;
            app.UnloadStiffness = UnloadingStiffness;
            app.PreUnloadIndex = PreUnloadIndex;
            app.PostLoadStartIndex = PostLoadingIndex;
            
            switch width(export_UI_Table)
                case 6
                    app.UITable_2.Data = [[1:height(export_UI_Table)]',export_UI_Table, NaN+ zeros([height(export_UI_Table),4])];
                case 11
                    app.UITable_2.Data = export_UI_Table;
            end
        end

        % Clicked callback: EventListBox_Detrend
        function EventListBox_DetrendClicked(app, event)
            %cla(app.UIAxes4)
            SlipData = app.Data.Disp;
            FrictionData = app.Data.Friction;
            TimeData = app.Data.Time;
            IndexData = app.Data.Index;

            plot(app.UIAxes4, SlipData, FrictionData) 
            hold(app.UIAxes4,'on');
            item = event.InteractionInformation.Item;
            app.StepNumber = item;
            Index_Step = app.UITable_2.Data(app.StepNumber,6);
            plot(app.UIAxes4, app.UITable_2.Data(app.StepNumber,2), app.UITable_2.Data(app.StepNumber,3),'ro', 'MarkerFaceColor', 'r')
            if isnan(app.UITable_2.Data(app.StepNumber,8))
                step_amount = app.PresetDisplacementAmountmEditField.Value;
                step_amount_down = app.PresetDisplacementAmountmEditField.Value;
                vstep_indices = (SlipData < SlipData(IndexData == Index_Step)+step_amount) & (SlipData > SlipData(IndexData == Index_Step)-step_amount_down) & (IndexData < app.PreUnloadIndex);
            else
                vstep_indices = (IndexData > app.UITable_2.Data(app.StepNumber,8) & IndexData < app.UITable_2.Data(app.StepNumber,9));
                test = SlipData(vstep_indices);
                if app.UITable_2.Data(app.StepNumber,2) < test(1) | app.UITable_2.Data(app.StepNumber,2) > test(end)
                    step_amount = app.PresetDisplacementAmountmEditField.Value;
                    step_amount_down = app.PresetDisplacementAmountmEditField.Value;
                    vstep_indices = (SlipData < SlipData(IndexData == Index_Step)+step_amount & SlipData > SlipData(IndexData == Index_Step)-step_amount_down & FrictionData > 0.1);
                end
            end
            disp = SlipData(vstep_indices);
            time = TimeData(vstep_indices);
            mu = FrictionData(vstep_indices);
            eff_normal = app.Data.Eff_Normal(vstep_indices);
            old_index = IndexData(vstep_indices);
            new_index = [1:length(disp)]';

            ylim(app.UIAxes4, [min(mu)-(max(mu)-min(mu))/4, max(mu)+(max(mu)-min(mu))/4])
            xlim(app.UIAxes4, [min(disp), max(disp)])

            if ~isnan(app.UITable_2.Data(app.StepNumber, 10))
                m = app.UITable_2.Data(app.StepNumber, 10);
                Slip_Reference = app.UITable_2.Data(app.StepNumber, 11);
                if m == 0
                    Friction_Detrend = mu;
                else
                    Friction_Detrend = mu + m*(Slip_Reference - disp);
                end
                plot(app.DetrendedAxes,disp,Friction_Detrend,'k.')
            end
        end

        % Button pushed function: FitTrendButton
        function FitTrendButtonPushed(app, event)
            % --- Executes on button press in FitTrendButton.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)

            %%% Get the point locations.
                Point1_SlipString = get(app.Point1SlipValue,'Value');
                Point2_SlipString = get(app.Point2SlipValue,'Value');
                Point1_Slip = str2double(Point1_SlipString);
                Point2_Slip = str2double(Point2_SlipString);
            %%% Get the slip, friction, and time data.
                SlipData = app.Data.Disp;
                FrictionData = app.Data.Friction;
                TimeData = app.Data.Time;
                IndexData = app.Data.Index;
            %%% Determine the slip and friction data from only the visible portion of
            %%% the main axes.
                XLimZoom = get(app.UIAxes4,'XLim');
                [~, i] = min(abs(SlipData - XLimZoom(1)));
                [~, ii] = min(abs(SlipData - XLimZoom(2)));
                SlipZoom = SlipData(i:ii);
                FrictionZoom = FrictionData(i:ii);
                TimeZoom = TimeData(i:ii);
                IndexZoom = IndexData(i:ii);
            %%% Determine the section of the data to fit the linear trend from the
            %%% locations of the set points.
                [~, i] = min(abs(SlipZoom - Point1_Slip));
                [~, ii] = min(abs(SlipZoom - Point2_Slip));
            %%% See if there is a previous detrend line plotted and delete it.
                %children = get(gcf, 'children');
                %if numel(children) > 1
                %    delete(children(1:end-1));
                %end
            %%% Do the linear fit and plot the line on the main axes.
                p = polyfit(SlipZoom(i:ii),FrictionZoom(i:ii),1);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,SlipZoom,p(1)*SlipZoom + p(2),'r')
                hold(app.UIAxes4,'off');
            %%% Display the latex_slope value in the GUI latex_slope box.
                set(app.SlopeValue, 'Value', num2str(p(1)));
            %%% Store the zoomed slip and friction values, and the linear fit latex_slope
            %%% value.
                setappdata(app.SlopeValue,'SlipZoom_Data',SlipZoom);
                setappdata(app.SlopeValue,'FrictionZoom_Data',FrictionZoom);
                setappdata(app.SlopeValue,'TimeZoom_Data',TimeZoom);
                setappdata(app.SlopeValue,'Slope_Value',p(1));
        end

        % Button pushed function: DetrendButton_2
        function DetrendButton_2Pushed(app, event)

                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)

            %%% Get the slip, friction, normal stress, and time data.
                SlipData = app.Data.Disp;
                FrictionData = app.Data.Friction;
                TimeData = app.Data.Time;
                NormStressData = app.Data.Eff_Normal;
                IndexData = app.Data.Index;

            %%% Determine the slip and friction data from only the visible portion of
            %%% the main axes.
                YLimZoom = get(app.UIAxes4,'YLim');
                XLimZoom = get(app.UIAxes4,'XLim');
                [~, i] = min(abs(SlipData - XLimZoom(1)));
                [~, ii] = min(abs(SlipData - XLimZoom(2)));
                
                IndexZoom = IndexData(i:ii);
                if IndexZoom(end) > app.PreUnloadIndex
                    temp = [i:ii];
                    ii = temp(IndexZoom == app.PreUnloadIndex);
                end
                SlipZoom = SlipData(i:ii);
                FrictionZoom = FrictionData(i:ii);
                TimeZoom = TimeData(i:ii);
                NormStressZoom = NormStressData(i:ii);
                if any(FrictionZoom > YLimZoom(2)) | any(FrictionZoom < YLimZoom(1))
                    zoom_index = (FrictionZoom<YLimZoom(2) & FrictionZoom>YLimZoom(1));
                    FrictionZoom = FrictionZoom(zoom_index);
                    TimeZoom = TimeZoom(zoom_index);
                    NormStressZoom = NormStressZoom(zoom_index);
                    IndexZoom = IndexZoom(zoom_index);
                    SlipZoom = SlipZoom(zoom_index);
                end
            %%% Read linear fit latex_slope value from text box, in case it has been manually
            %%% entered.
                m_String = get(app.SlopeValue,'Value');
                m = str2double(m_String);
            %%% Get the reference slip value for detrending, use the value from the
            %%% picked reference point.
                Slip_Ref_String = get(app.ReferencePointSlipValue, 'Value');
                Slip_Reference = str2double(Slip_Ref_String);
            %%% Detrend, reference to zero, and plot in fitting axes.
                if m == 0
                    Friction_Detrend = FrictionZoom;
                else
                    Friction_Detrend = FrictionZoom + m*(Slip_Reference - SlipZoom);
                end
                Slip_ZeroRef = SlipZoom - SlipZoom(1);
                Time_ZeroRef = TimeZoom - TimeZoom(1);
            %%% Plot detrended data in Fitting Axes. If 'velocity step' is selected in
            %%% the Event Type Panel, plot vs. displacement. If 'slide-hold-slide' is
            %%% selected, plot vs. time.
                %if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                plot(app.DetrendedAxes,Slip_ZeroRef,Friction_Detrend,'k.');
                xlabel(app.DetrendedAxes,'Load Point Displacement (\mum)');
                ylabel(app.DetrendedAxes,'Friction Coefficient');
            %%% Store the zero-referenced data.
                %setappdata(app.DetrendButton_2,'FrictionDetrend_Data',Friction_Detrend);
                %setappdata(app.DetrendButton_2,'SlipZeroRef_Data',Slip_ZeroRef);
                %setappdata(app.DetrendButton_2,'TimeZeroRef_Data',Time_ZeroRef);
                %setappdata(app.SlopeValue,'SlipZoom_Data',SlipZoom);
                %setappdata(app.SlopeValue,'FrictionZoom_Data',FrictionZoom);
                %setappdata(app.SlopeValue,'TimeZoom_Data',TimeZoom);
                %setappdata(app.SlopeValue, 'NormStressZoomData', NormStressZoom);
                setappdata(app.SlopeValue, 'IndexZoom_Data', IndexZoom);
        end

        % Button pushed function: SetPoint1Button
        function SetPoint1ButtonPushed(app, event)
            % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.Point1SlipValue, 'Value', num2str(Position(1)));
                set(app.Point1MuValue, 'Value', num2str(Position(2)));
        end

        % Button pushed function: SetPoint2Button
        function SetPoint2ButtonPushed(app, event)
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.Point2SlipValue, 'Value', num2str(Position(1)));
                set(app.Point2MuValue, 'Value', num2str(Position(2)));
        end

        % Button pushed function: SetReferencePointButton
        function SetReferencePointButtonPushed(app, event)
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                IofC = app.Data.Index(dcm_Info.DataIndex);
                Position = dcm_Info.Position;

                datacursormode off
                set(app.ReferencePointSlipValue, 'Value', num2str(Position(1)));
                set(app.ReferencePointMuValue, 'Value', num2str(Position(2)));
            %%% Store slip value for detrending later.
                setappdata(app.ReferencePointSlipValue,'ReferencePoint_IndexValue', IofC);
        end

        % Button pushed function: ClearPointsButton
        function ClearPointsButtonPushed(app, event)
                       %%% See if there is a previous detrend line plotted and delete it.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)   

                children = get(app.UIAxes4, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Clear all the displayed and stored values from any previous detrend.
                set(app.Point1SlipValue, 'Value', '');
                set(app.Point1MuValue, 'Value', '');
                set(app.Point2SlipValue, 'Value', '');
                set(app.Point2MuValue, 'Value','');
                set(app.ReferencePointSlipValue, 'Value', '');
                set(app.ReferencePointMuValue, 'Value','');
                setappdata(app.ReferencePointSlipValue,'ReferencePoint_IndexValue', '');
                set(app.SlopeValue, 'Value', '');
                %setappdata(app.SlopeValue,'Slope_Value', '');
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)
            IndexZoom = getappdata(app.SlopeValue, 'IndexZoom_Data');
            Slope = str2double(app.SlopeValue.Value);
            ReferenceIndex = getappdata(app.ReferencePointSlipValue, 'ReferencePoint_IndexValue');
            if isempty(ReferenceIndex)
                ReferenceIndex = app.UITable_2.Data(app.StepNumber,6);
            end
            app.UITable_2.Data(app.StepNumber, 8:11) = [min(IndexZoom), max(IndexZoom), Slope, ReferenceIndex];
            cla(app.UIAxes4)
        end

        % Button pushed function: SaveTableButton_2
        function SaveTableButton_2Pushed(app, event)
            export_UI_Table_2 = app.UITable_2.Data;
            disp = app.Data.Disp;
            index = app.Data.Index;
            time = app.Data.Time;
            friction = app.Data.Friction;
            eff_normal = app.Data.Eff_Normal;
            LoadingStiffness = app.LoadingStiffness;
            UnloadingStiffness = app.UnloadStiffness; 
            PreUnloadIndex = app.PreUnloadIndex;
            PostLoadingIndex = app.PostLoadStartIndex;

            for i = [1:height(export_UI_Table_2)]
                EventInfo = struct('VS_num', export_UI_Table_2(i,1), 'StepDisp', export_UI_Table_2(i,2), 'StepFriction', export_UI_Table_2(i,3), 'V0', export_UI_Table_2(i,4), 'VF', export_UI_Table_2(i,5), 'StepIndex', export_UI_Table_2(i,6), 'HoldTime', export_UI_Table_2(i,7), 'WindowStartIndex', export_UI_Table_2(i,8), 'WindowEndIndex', export_UI_Table_2(i,9));

                step_indices = (index > export_UI_Table_2(i,8) & index < export_UI_Table_2(i,9));
                step_disp = disp(step_indices);
                step_normal = eff_normal(step_indices);
                step_time = time(step_indices);
                step_index = index(step_indices);

                if export_UI_Table_2(i,10) ~= 0
                    ReferenceIndex = export_UI_Table_2(i,11);
                    ReferenceDisp = disp(index == ReferenceIndex);
                    ReferenceMu = friction(index == ReferenceIndex);
                    Slope = export_UI_Table_2(i,10);
                    step_friction_detrended = friction(step_indices) + Slope*(ReferenceDisp - step_disp);
                    step_friction = friction(step_indices);
                    DetrendParameters = struct('ReferenctPoint', [ReferenceDisp, ReferenceMu, ReferenceIndex], 'Slope', Slope);
                else
                    step_friction_detrended = friction(step_indices);
                    step_friction = friction(step_indices);
                    DetrendParameters = struct('ReferenctPoint', [NaN, NaN,  NaN], 'Slope',  NaN);
                end

                Event(i) = struct('EventInfo', EventInfo, 'DetrendParameters', DetrendParameters, 'LoadPointDisplacementData', step_disp, 'TimeData', step_time, 'FrictionDataDetrended', step_friction_detrended, 'FrictionData', step_friction, 'NormalStressData', step_normal, 'IndexData', step_index, 'AgingLawParameters', [], 'SlipLawParameters', [], 'SlipLawParameters_2State', [], 'AgingLawParameters_2State', []);
            end

            save(app.SaveFileNameEditField.Value + ".mat", "Event", "export_UI_Table_2", "disp", "time", "friction", "eff_normal", "index", "UnloadingStiffness", "LoadingStiffness", "PreUnloadIndex", "PostLoadingIndex")
        end

        % Button pushed function: LoadTableButton_2
        function LoadTableButton_2Pushed(app, event)
            % When load table button is pushed then the file listed in the
            % file name box is loaded, which should include the output of
            % the Window and Detrend Process
            try
                load(app.EventFileNameEditField_4.Value + ".mat", "export_UI_Table_2");
                load(app.EventFileNameEditField_4.Value + ".mat", "Event");
                app.EventListBox.Items = "Event " + (1:height(export_UI_Table_2));
                load(app.EventFileNameEditField_4.Value + ".mat", "time", "disp", "friction", "eff_normal", "index", "LoadingStiffness", "UnloadingStiffness", "PreUnloadIndex", "PostLoadingIndex");
                app.Event = Event;
                app.UITable_Fit.Data = {};
                if app.DownsampleButton.Value 
                    for event_num = 1:length(app.Event)
                        app.Event(event_num).LoadPointDisplacementData = downsample(app.Event(event_num).LoadPointDisplacementData, app.DownsampleFactorEditField.Value);
                        app.Event(event_num).FrictionData = downsample(app.Event(event_num).FrictionData, app.DownsampleFactorEditField.Value);
                        app.Event(event_num).FrictionDataDetrended = downsample(app.Event(event_num).FrictionDataDetrended, app.DownsampleFactorEditField.Value);
                        app.Event(event_num).IndexData = downsample(app.Event(event_num).IndexData,app.DownsampleFactorEditField.Value);
                        app.Event(event_num).TimeData = downsample(app.Event(event_num).TimeData,app.DownsampleFactorEditField.Value);
                        app.Event(event_num).NormalStressData = downsample(app.Event(event_num).NormalStressData,app.DownsampleFactorEditField.Value);
                        [~, idx] = min(abs(app.Event(event_num).IndexData - app.Event(event_num).EventInfo.StepIndex));
                        app.Event(event_num).EventInfo.StepIndex = app.Event(event_num).IndexData(idx);
                        [~, idx] = min(abs(app.Event(event_num).IndexData - app.Event(event_num).EventInfo.WindowStartIndex));
                        app.Event(event_num).EventInfo.WindowStartIndex = app.Event(event_num).IndexData(idx);
                        [~, idx] = min(abs(app.Event(event_num).IndexData - app.Event(event_num).EventInfo.WindowEndIndex));
                        app.Event(event_num).EventInfo.WindowEndIndex = app.Event(event_num).IndexData(idx);
                    end
                    app.Data_forFit.Time = downsample(time,app.DownsampleFactorEditField.Value);
                    app.Data_forFit.Disp = downsample(disp,app.DownsampleFactorEditField.Value);
                    app.Data_forFit.Friction = downsample(friction,app.DownsampleFactorEditField.Value);
                    app.Data_forFit.Eff_Normal = downsample(eff_normal,app.DownsampleFactorEditField.Value);
                    app.Data_forFit.Index = downsample(index,app.DownsampleFactorEditField.Value);
                else
                    app.Data_forFit.Time = time;
                    app.Data_forFit.Disp = disp;
                    app.Data_forFit.Friction = friction;
                    app.Data_forFit.Eff_Normal = eff_normal;
                    app.Data_forFit.Index = index;
                end
            catch
                load(app.EventFileNameEditField_4.Value + ".mat", "Events", "UITable_Fit", "time", "disp", "friction", "eff_normal", "index", "LoadingStiffness", "UnloadingStiffness","PreUnloadIndex", "PostLoadingIndex");
                app.EventListBox.Items = "Event " + (1:length(Events));
                app.Event = Events;
                i = 0;
                app.Data_forFit.Time = time;
                app.Data_forFit.Disp = disp;
                app.Data_forFit.Friction = friction;
                app.Data_forFit.Eff_Normal = eff_normal;
                app.Data_forFit.Index = index;                
                for event_num = 1:length(Events)
                    if ~isempty(Events(event_num).AgingLawParameters)
                        i = i+1;
                        mu0 = Events(event_num).AgingLawParameters.mu_0(1);
                        a = Events(event_num).AgingLawParameters.a(1);
                        b1 = Events(event_num).AgingLawParameters.b1(1);
                        b2 = Events(event_num).AgingLawParameters.b2(1);
                        dc1 = Events(event_num).AgingLawParameters.d_c1(1);
                        dc2 = Events(event_num).AgingLawParameters.d_c2(1);
                        k = Events(event_num).AgingLawParameters.stiffness(1);
                        app.UITable_Fit.Data(i,:) = [event_num, mu0, a, b1, b2, dc1, dc2, k, 1, {'Aging'}];
                    end
                    if ~isempty(Events(event_num).SlipLawParameters)
                        i = i+1;
                        mu0 = Events(event_num).SlipLawParameters.mu_0(1);
                        a = Events(event_num).SlipLawParameters.a(1);
                        b1 = Events(event_num).SlipLawParameters.b1(1);
                        b2 = Events(event_num).SlipLawParameters.b2(1);
                        dc1 = Events(event_num).SlipLawParameters.d_c1(1);
                        dc2 = Events(event_num).SlipLawParameters.d_c2(1);
                        k = Events(event_num).SlipLawParameters.stiffness(1);
                        app.UITable_Fit.Data(i,:) = [event_num, mu0, a, b1, b2, dc1, dc2, k, 1, {'Slip'}];
                    end
                    if ~isempty(Events(event_num).AgingLawParameters_2State)
                        i = i+1;
                        mu0 = Events(event_num).AgingLawParameters_2State.mu_0(1);
                        a = Events(event_num).AgingLawParameters_2State.a(1);
                        b1 = Events(event_num).AgingLawParameters_2State.b1(1);
                        b2 = Events(event_num).AgingLawParameters_2State.b2(1);
                        dc1 = Events(event_num).AgingLawParameters_2State.d_c1(1);
                        dc2 = Events(event_num).AgingLawParameters_2State.d_c2(1);
                        k = Events(event_num).AgingLawParameters_2State.stiffness(1);
                        app.UITable_Fit.Data(i,:) = [event_num, mu0, a, b1, b2, dc1, dc2, k, 2, {'Aging'}];
                    end
                    if ~isempty(Events(event_num).SlipLawParameters_2State)
                        i = i+1;
                        mu0 = Events(event_num).SlipLawParameters_2State.mu_0(1);
                        a = Events(event_num).SlipLawParameters_2State.a(1);
                        b1 = Events(event_num).SlipLawParameters_2State.b1(1);
                        b2 = Events(event_num).SlipLawParameters_2State.b2(1);
                        dc1 = Events(event_num).SlipLawParameters_2State.d_c1(1);
                        dc2 = Events(event_num).SlipLawParameters_2State.d_c2(1);
                        k = Events(event_num).SlipLawParameters_2State.stiffness(1);
                        app.UITable_Fit.Data(i,:) = [event_num, mu0, a, b1, b2, dc1, dc2, k, 2, {'Slip'}];
                    end      
                end
            end

            app.UnloadStiffness = UnloadingStiffness;
            app.LoadingStiffness = LoadingStiffness;

            app.PreUnloadIndex = PreUnloadIndex;
            app.PostLoadStartIndex = PostLoadingIndex;
            app.LoadingStiffness1mEditField.Value = num2str(LoadingStiffness);
            app.UnLoadingStiffness1mEditField.Value = num2str(UnloadingStiffness);
            %app.UITable_2.Data = [[1:length(export_UI_Table_2.Data)]',export_UI_Table_2.Data, NaN+ zeros([length(export_UI_Table_2.Data),3])];
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)

            %%% See if there's any previous lines plotted and delete them.
            %children = get(app.FittingAxes, 'children');
            %if numel(children) > 1
            %    delete(children(1:end-1));
            %end
            
            %%% Get the stuff.
            v_i = (app.Event(app.StepNumber).EventInfo.V0);
            v_f = (app.Event(app.StepNumber).EventInfo.VF);
            NormStress = app.Event(app.StepNumber).NormalStressData(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex);
            Slip_ZeroRef = app.Event(app.StepNumber).LoadPointDisplacementData - app.Event(app.StepNumber).LoadPointDisplacementData(1);
            Time_ZeroRef = app.Event(app.StepNumber).TimeData - app.Event(app.StepNumber).TimeData(1);
            

            AgingStateVarFlag = app.AgingTwoStateGuessButton.Value;
            SlipStateVarFlag = app.SlipTwoStateGuessButton.Value;
            StiffnessFlag = ~app.StiffnessButton.Value;
            MuFlag = ~app.MuButton.Value;
            WeightFlag = app.WeightButton.Value;   

            %app.Event(app.StepNumber).FittingOptions = struct('EventType', OptionE, 'UseTwoStateVariables',...
            %        OptionS, 'ConstrainMu_0', OptionMu, 'ConstrainStiffness', OptionK,...
            %        'UseWeightFunction', OptionW);
            
            if isnan(app.Event(app.StepNumber).EventInfo.HoldTime) %VS
                EventFlag = true;
                Holds{1} = [];
                TimeOfStep = Time_ZeroRef(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex);
            else %SHS
                EventFlag = false;
                Holds{1} = app.Event(app.StepNumber).EventInfo.HoldTime;
                Holds{2} = Time_ZeroRef(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex);
                TimeOfStep = [];
            end
            %%% End of get the stuff

            %%% Check what fits to do.
            AgingLawFlag =  app.AgingCheckBox.Value;
            SlipLawFlag = app.SlipCheckBox.Value;
            legend(app.FittingAxes,'-DynamicLegend');
            hold(app.FittingAxes, 'on');
            if AgingLawFlag == 1
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 1);
                TwoState = false;
                StateLawFlag = 1;
                TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                if AgingStateVarFlag
                    [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 2);
                    TwoState = true;
                    StateLawFlag = 1;
                    TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                end
            end
            if SlipLawFlag == 1
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 1);
                TwoState = false;
                StateLawFlag = 0;
                TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                if SlipStateVarFlag
                    [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 2);
                    TwoState = true;
                    StateLawFlag = 0;
                    TestFit(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                          StateLawFlag, TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i);
                end
            end

            hold(app.FittingAxes, 'off');
        end

        % Clicked callback: EventListBox
        function EventListBoxClicked(app, event)
            cla(app.FittingAxes, 'reset')
            item = event.InteractionInformation.Item;
            app.StepNumber = item;
          
            app.Guess_muValue_a1.Value = num2str(mean(app.Event(app.StepNumber).FrictionDataDetrended(app.Event(app.StepNumber).IndexData < app.Event(app.StepNumber).EventInfo.StepIndex)));
            app.Guess_muValue_s1.Value = num2str(mean(app.Event(app.StepNumber).FrictionDataDetrended(app.Event(app.StepNumber).IndexData < app.Event(app.StepNumber).EventInfo.StepIndex)));
            app.Guess_muValue_a2.Value = num2str(mean(app.Event(app.StepNumber).FrictionDataDetrended(app.Event(app.StepNumber).IndexData < app.Event(app.StepNumber).EventInfo.StepIndex)));
            app.Guess_muValue_s2.Value = num2str(mean(app.Event(app.StepNumber).FrictionDataDetrended(app.Event(app.StepNumber).IndexData < app.Event(app.StepNumber).EventInfo.StepIndex)));
            
            if height(app.UITable_Fit.Data) > 0 
                if ismember(item, cell2mat(app.UITable_Fit.Data(:,1)))
                    stuff = app.UITable_Fit.Data(cell2mat(app.UITable_Fit.Data(:,1)) == item,:);
                    for i = [1:height(stuff)]
                        law = stuff{i,10};
                        statevar = stuff{i,9};
                        if law == "Aging"
                            if statevar == 1
                                app.Guess_muValue_a1.Value = num2str(stuff{i, 2});
                                app.Guess_aValue_a1.Value = num2str(stuff{i, 3});
                                app.Guess_bValue_a1.Value = num2str(stuff{i, 4});
                                app.Guess_dcValue_a1.Value = num2str(stuff{i, 6});
                                app.Guess_kValue_a1.Value = num2str(stuff{i, 8});
                            else
                                app.Guess_muValue_a2.Value = num2str(stuff{i, 2});
                                app.Guess_aValue_a2.Value = num2str(stuff{i, 3});
                                app.Guess_bValue_a2.Value = num2str(stuff{i, 4});
                                app.Guess_b2Value_a2.Value = num2str(stuff{i, 5});
                                app.Guess_dcValue_a2.Value = num2str(stuff{i, 6});
                                app.Guess_dc2Value_a2.Value = num2str(stuff{i, 7});
                                app.Guess_kValue_a2.Value = num2str(stuff{i, 8});
                            end
                        end
                        if law == "Slip"
                            if statevar == 1
                                app.Guess_muValue_s1.Value = num2str(stuff{i, 2});
                                app.Guess_aValue_s1.Value = num2str(stuff{i, 3});
                                app.Guess_bValue_s1.Value = num2str(stuff{i, 4});
                                app.Guess_dcValue_s1.Value = num2str(stuff{i, 6});
                                app.Guess_kValue_s1.Value = num2str(stuff{i, 8});
                            else
                                app.Guess_muValue_s2.Value = num2str(stuff{i, 2});
                                app.Guess_aValue_s2.Value = num2str(stuff{i, 3});
                                app.Guess_bValue_s2.Value = num2str(stuff{i, 4});
                                app.Guess_b2Value_s2.Value = num2str(stuff{i, 5});
                                app.Guess_dcValue_s2.Value = num2str(stuff{i, 6});
                                app.Guess_dc2Value_s2.Value = num2str(stuff{i, 7});
                                app.Guess_kValue_s2.Value = num2str(stuff{i, 8});
                            end
                        end
                    end
                end
            end

            disp = app.Event(app.StepNumber).LoadPointDisplacementData - app.Event(app.StepNumber).LoadPointDisplacementData(1);
            mu_plot = app.Event(app.StepNumber).FrictionDataDetrended;
            time = app.Event(app.StepNumber).TimeData - app.Event(app.StepNumber).TimeData(1);

            if isnan(app.Event(app.StepNumber).EventInfo.HoldTime)
                plot(app.FittingAxes, disp, mu_plot, 'b-', 'HandleVisibility','off')
                xlabel(app.FittingAxes, 'Displacement (μm)')
                ylim(app.FittingAxes, [min(mu_plot)-0.005, max(mu_plot)+0.005])
            else
                plot(app.FittingAxes, time, mu_plot, 'b-', 'HandleVisibility','off')
                xlabel(app.FittingAxes, 'Time (s)')
                ylim(app.FittingAxes, [min(mu_plot)-0.005, max(mu_plot)+0.005])
            end
            ylabel(app.FittingAxes, 'Friction, μ')
            hold(app.FittingAxes,'on');

            app.LocNormStress.Value = num2str(round(mean(app.Event(app.StepNumber).NormalStressData),1));
        end

        % Value changed function: Guess_muValue_a1
        function Guess_muValue_a1ValueChanged(app, event)
            value = app.Guess_muValue_a1.Value;
            app.Guess_muValue_s1.Value = value;
        end

        % Value changed function: Guess_aValue_a1
        function Guess_aValue_a1ValueChanged(app, event)
            value = app.Guess_aValue_a1.Value;
            app.Guess_aValue_s1.Value = value;
        end

        % Value changed function: Guess_bValue_a1
        function Guess_bValue_a1ValueChanged(app, event)
            value = app.Guess_bValue_a1.Value;
            app.Guess_bValue_s1.Value = value;
        end

        % Value changed function: Guess_dcValue_a1
        function Guess_dcValue_a1ValueChanged(app, event)
            value = app.Guess_dcValue_a1.Value;
            app.Guess_dcValue_s1.Value = value;
        end

        % Value changed function: Guess_kValue_a1
        function Guess_kValue_a1ValueChanged(app, event)
            value = app.Guess_kValue_a1.Value;
            app.Guess_kValue_s1.Value = value;
        end

        % Button pushed function: ClearTestLines
        function ClearTestLinesButtonPushed(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
%            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearTestLines (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %%% See if there's any test lines plotted and delete them.
            children = app.FittingAxes.Children;
            if isnan(app.Event(app.StepNumber).EventInfo.HoldTime)
                EventFlag = true;
            end
            if numel(children) > 1
                for i = 1:numel(children)
            %%% Check if fit lines are present.
                    if strcmp('Aging Law Fit', get(children(i), 'DisplayName'))
                        delete(children(i));
                    elseif strcmp('Slip Law Fit', get(children(i), 'DisplayName'))
                        delete(children(i));
                    elseif strcmp('Aging Law Fit 2S', get(children(i), 'DisplayName'))
                        delete(children(i));
                    elseif strcmp('Slip Law Fit 2S', get(children(i), 'DisplayName'))
                        delete(children(i));
                    elseif strcmp('--', get(children(i), 'LineStyle'))
                        delete(children(i));
                    end
                end
            %%% Reset the legend
                LegLoc = 'best';
                if exist('PAgingFit', 'var') & exist('PSlipFit', 'var')
                    legend(handles.FittingAxes, [PAgingFit, PSlipFit], 'Aging Law Fit',...
                        'Slip Law Fit', 'Location', LegLoc);
                elseif exist('PAgingFit', 'var') & exist('PSlipFit', 'var') == 0
                    legend(handles.FittingAxes, PAgingFit, 'Aging Law Fit',...
                         'Location', LegLoc);
                elseif exist('PAgingFit', 'var') == 0 & exist('PSlipFit', 'var')
                    legend(handles.FittingAxes, PSlipFit, 'Slip Law Fit',...
                         'Location', LegLoc);
                else
                    legend('off');
                end
            end
        end

        % Value changed function: Guess_muValue_a2
        function Guess_muValue_a2ValueChanged(app, event)
            value = app.Guess_muValue_a2.Value;
            app.Guess_muValue_s2.Value = value;
        end

        % Value changed function: Guess_aValue_a2
        function Guess_aValue_a2ValueChanged(app, event)
            value = app.Guess_aValue_a2.Value;
            app.Guess_aValue_s2.Value = value;
        end

        % Value changed function: Guess_bValue_a2
        function Guess_bValue_a2ValueChanged(app, event)
            value = app.Guess_bValue_a2.Value;
            app.Guess_bValue_s2.Value = value;
        end

        % Value changed function: Guess_b2Value_a2
        function Guess_b2Value_a2ValueChanged(app, event)
            value = app.Guess_b2Value_a2.Value;
            app.Guess_b2Value_s2.Value = value;
        end

        % Value changed function: Guess_dcValue_a2
        function Guess_dcValue_a2ValueChanged(app, event)
            value = app.Guess_dcValue_a2.Value;
            app.Guess_dcValue_s2.Value = value;
        end

        % Value changed function: Guess_dc2Value_a2
        function Guess_dc2Value_a2ValueChanged(app, event)
            value = app.Guess_dc2Value_a2.Value;
            app.Guess_dc2Value_s2.Value = value;
        end

        % Value changed function: Guess_kValue_a2
        function Guess_kValue_a2ValueChanged(app, event)
            value = app.Guess_kValue_a2.Value;
            app.Guess_kValue_s2.Value = value;
        end

        % Button pushed function: FitStepButton
        function FitStepButtonPushed(app, event)

            % % Create GUIDE-style callback args - Added by Migration Tool
            %%[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %
            %% hObject    handle to FitButton (see GCBO)
            %% eventdata  reserved - to be defined in a future version of MATLAB
            %% handles    structure with handles and user data (see GUIDATA)
            %
            
            %%%% Clear any previously plotted fits.
            %children = get(handles.FittingAxes, 'children');
            %if numel(children) > 1
            %    for i = 1:numel(children)
            %        if strcmp('-', get(children(i), 'LineStyle'))
            %            delete(children(i));
            %        end
            %    end
            %end
            %
            
            %%%% Get the stuff.

            %    %[x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
            %    %    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
            %    %    varargout] = GetStuff(app, handles);
            %%%% Check if using sample slip data.
            %    SampleSlipFlag = getappdata(handles.FitButton, 'SampleSlipFlag');
            %%%% Check what fits to do.
            %    AgingLawFlag = getappdata(handles.FitButton, 'AgingLawFlag');
            %    SlipLawFlag = getappdata(handles.FitButton, 'SlipLawFlag');
            %%%% Store the normal stress.
            %    setappdata(handles.FitButton, 'NormStressStep', NormStress);
            %%%% Get the friction data and carry out the inversion.
            %    Friction_Detrend = getappdata(handles.DetrendButton,'FrictionDetrend_Data');
            %    FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);
            %
            %%% Get the stuff.
            v_i = (app.Event(app.StepNumber).EventInfo.V0);
            v_f = (app.Event(app.StepNumber).EventInfo.VF);
            NormStress = app.Event(app.StepNumber).NormalStressData(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex);
            Slip_ZeroRef = app.Event(app.StepNumber).LoadPointDisplacementData - app.Event(app.StepNumber).LoadPointDisplacementData(1);
            Time_ZeroRef = app.Event(app.StepNumber).TimeData - app.Event(app.StepNumber).TimeData(1);
            Friction_Detrend = app.Event(app.StepNumber).FrictionDataDetrended;
            FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);

            SampleSlipFlag = 0;

            AgingStateVarFlag = app.AgingTwoStateGuessButton.Value;
            SlipStateVarFlag = app.SlipTwoStateGuessButton.Value;
            StiffnessFlag = ~app.StiffnessButton.Value;
            MuFlag = ~app.MuButton.Value;
            WeightFlag = app.WeightButton.Value;   
            
            Holds = [];
            if isnan(app.Event(app.StepNumber).EventInfo.HoldTime)
                EventFlag = true;
                Holds{1} = [];
                TimeOfStep = Time_ZeroRef(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex);
            else
                EventFlag = false;
                Holds{1} = app.Event(app.StepNumber).EventInfo.HoldTime; 
                Holds{2} = Time_ZeroRef(app.Event(app.StepNumber).IndexData == app.Event(app.StepNumber).EventInfo.StepIndex); %Time of Hold
                TimeOfStep = [];
            end

            AgingLawFlag =  app.AgingCheckBox.Value;
            SlipLawFlag = app.SlipCheckBox.Value;

            if WeightFlag    
                [Weight, ~] = WeightCalc(app);
            else
                N = numel(Slip_ZeroRef);
                Weight = ones(1, N);
                app.WeightParameters = struct('Weights', Weight, 'Weight_Info', nan(3,1));
            end                    

            app.AgingLawFitData = struct();
            app.AgingLawFitData_2State = struct();
            app.SlipLawFitData = struct();
            app.SlipLawFitData_2State = struct();
            %%%%------------------------ Start Fits -----------------------------%%%
            if AgingLawFlag == 1
                StateLawFlag = 1;
                StateVarFlag = 0;
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 1);
                [x_0, x_f, EventError, Slip_f, Mu_f, Covar_f, FrictionCOD_Event, exitflag_f, GIC, logL] = FitEvent(app, x_0, x_e, Vars_i, v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                Slip_ZeroRef,Time_ZeroRef, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag,MuFlag,Weight,SampleSlipFlag, FrictionVariability, Holds);
                %setappdata(handles.FitButton,'AgingErrors',AgingError);
                x_fit = x_f(1,:);
                app.AgingLawFitData = struct('x_0', x_0, 'x_f', x_fit, 'EventError', EventError, 'Slip_f', Slip_f, 'Mu_f', Mu_f, 'Covar_f', Covar_f, 'ExitFlag', exitflag_f, 'FrictionCOD', FrictionCOD_Event, 'GIC', GIC, 'logL', logL);
                %app.AgingIC = 
            end

            if AgingLawFlag == 1 & AgingStateVarFlag == 1
                StateLawFlag = 1;
                StateVarFlag = 1;
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 2);
                [x_0, x_f, EventError, Slip_f, Mu_f, Covar_f, FrictionCOD_Event, exitflag_f, GIC, logL] = FitEvent(app, x_0, x_e, Vars_i, v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                Slip_ZeroRef,Time_ZeroRef, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag,MuFlag,Weight,SampleSlipFlag, FrictionVariability, Holds);
                x_fit = x_f(1,:);
                %setappdata(handles.FitButton,'AgingErrors',AgingError);
                app.AgingLawFitData_2State = struct('x_0', x_0, 'x_f', x_fit, 'EventError', EventError, 'Slip_f', Slip_f, 'Mu_f', Mu_f, 'Covar_f', Covar_f, 'ExitFlag', exitflag_f, 'FrictionCOD', FrictionCOD_Event, 'GIC', GIC, 'logL', logL);
            end

            if SlipLawFlag == 1
                StateLawFlag = 0;
                StateVarFlag = 0;
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 1);
                [x_0, x_f, EventError, Slip_f, Mu_f, Covar_f, FrictionCOD_Event, exitflag_f, GIC, logL] = FitEvent(app, x_0, x_e, Vars_i, v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                Slip_ZeroRef,Time_ZeroRef, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag,MuFlag,Weight,SampleSlipFlag, FrictionVariability, Holds);
                %setappdata(handles.FitButton,'AgingErrors',AgingError);
                x_fit = x_f(1,:);
                app.SlipLawFitData = struct('x_0', x_0, 'x_f', x_fit, 'EventError', EventError, 'Slip_f', Slip_f, 'Mu_f', Mu_f, 'Covar_f', Covar_f, 'ExitFlag', exitflag_f, 'FrictionCOD', FrictionCOD_Event, 'GIC', GIC, 'logL', logL);
            end

            if SlipLawFlag == 1 & SlipStateVarFlag == 1
                StateLawFlag = 0;
                StateVarFlag = 1;
                [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Slip', 2);
                [x_0, x_f, EventError, Slip_f, Mu_f, Covar_f, FrictionCOD_Event, exitflag_f, GIC, logL] = FitEvent(app, x_0, x_e, Vars_i,v_i, v_f,NormStress,TimeOfStep,Friction_Detrend,...
                Slip_ZeroRef,Time_ZeroRef, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag,MuFlag,Weight,SampleSlipFlag, FrictionVariability, Holds);
                %setappdata(handles.FitButton,'AgingErrors',AgingError);
                x_fit = x_f(1,:);
                app.SlipLawFitData_2State = struct('x_0', x_0, 'x_f', x_fit, 'EventError', EventError, 'Slip_f', Slip_f, 'Mu_f', Mu_f, 'Covar_f', Covar_f, 'ExitFlag', exitflag_f, 'FrictionCOD', FrictionCOD_Event, 'GIC', GIC, 'logL', logL);
            end
        end

        % Value changed function: AgingCheckBox
        function AgingCheckBoxValueChanged(app, event)
            value = app.AgingCheckBox.Value;
            if value
                if app.AgingTwoStateGuessButton.Value
                    set(findall(app.AgingFittingPanel, '-property', 'Enable'), 'Enable', 'on')
                else
                    set(findall(app.AgingFittingPanel, '-property', 'Enable'), 'Enable', 'on')
                    set(app.Guess_dc2Value_a2, 'Enable', 'off')
                    set(app.Guess_aValue_a2, 'Enable', 'off')
                    set(app.Guess_b2Value_a2, 'Enable', 'off')
                    set(app.Guess_bValue_a2, 'Enable', 'off')
                    set(app.Guess_dcValue_a2, 'Enable', 'off')
                    set(app.Guess_kValue_a2, 'Enable', 'off')
                    set(app.Guess_muValue_a2, 'Enable', 'off')   
                end
            else
                set(findall(app.AgingFittingPanel, '-property', 'Enable'),...
                'Enable', 'off')
            end   
        end

        % Value changed function: SlipCheckBox
        function SlipCheckBoxValueChanged(app, event)
            value = app.SlipCheckBox.Value;
            if value
                if app.SlipTwoStateGuessButton.Value
                    set(findall(app.SlipFittingPanel, '-property', 'Enable'), 'Enable', 'on')
                else
                    set(findall(app.SlipFittingPanel, '-property', 'Enable'), 'Enable', 'on')
                    set(app.Guess_dc2Value_s2, 'Enable', 'off')
                    set(app.Guess_aValue_s2, 'Enable', 'off')
                    set(app.Guess_b2Value_s2, 'Enable', 'off')
                    set(app.Guess_bValue_s2, 'Enable', 'off')
                    set(app.Guess_dcValue_s2, 'Enable', 'off')
                    set(app.Guess_kValue_s2, 'Enable', 'off')
                    set(app.Guess_muValue_s2, 'Enable', 'off')
                end
            else
                set(findall(app.SlipFittingPanel, '-property', 'Enable'),...
                'Enable', 'off')
            end              
        end

        % Value changed function: AgingTwoStateGuessButton
        function AgingTwoStateGuessButtonValueChanged(app, event)
            value = app.AgingTwoStateGuessButton.Value;
            if value
                set(app.Guess_dc2Value_a2, 'Enable', 'on')
                set(app.Guess_aValue_a2, 'Enable', 'on')
                set(app.Guess_b2Value_a2, 'Enable', 'on')
                set(app.Guess_bValue_a2, 'Enable', 'on')
                set(app.Guess_dcValue_a2, 'Enable', 'on')
                set(app.Guess_kValue_a2, 'Enable', 'on')
                set(app.Guess_muValue_a2, 'Enable', 'on')            
            else
                set(app.Guess_dc2Value_a2, 'Enable', 'off')
                set(app.Guess_aValue_a2, 'Enable', 'off')
                set(app.Guess_b2Value_a2, 'Enable', 'off')
                set(app.Guess_bValue_a2, 'Enable', 'off')
                set(app.Guess_dcValue_a2, 'Enable', 'off')
                set(app.Guess_kValue_a2, 'Enable', 'off')
                set(app.Guess_muValue_a2, 'Enable', 'off')        
            end           
        end

        % Value changed function: SlipTwoStateGuessButton
        function SlipTwoStateGuessButtonValueChanged(app, event)
            value = app.SlipTwoStateGuessButton.Value;
            if value
                set(app.Guess_dc2Value_s2, 'Enable', 'on')
                set(app.Guess_aValue_s2, 'Enable', 'on')
                set(app.Guess_b2Value_s2, 'Enable', 'on')
                set(app.Guess_bValue_s2, 'Enable', 'on')
                set(app.Guess_dcValue_s2, 'Enable', 'on')
                set(app.Guess_kValue_s2, 'Enable', 'on')
                set(app.Guess_muValue_s2, 'Enable', 'on')            
            else
                set(app.Guess_dc2Value_s2, 'Enable', 'off')
                set(app.Guess_aValue_s2, 'Enable', 'off')
                set(app.Guess_b2Value_s2, 'Enable', 'off')
                set(app.Guess_bValue_s2, 'Enable', 'off')
                set(app.Guess_dcValue_s2, 'Enable', 'off')
                set(app.Guess_kValue_s2, 'Enable', 'off')
                set(app.Guess_muValue_s2, 'Enable', 'off')        
            end                       
        end

        % Value changed function: WeightButton
        function WeightButtonValueChanged(app, event)
            value = app.WeightButton.Value;
            if value
                set(findall(app.WeightPanel, '-property', 'Enable'), 'Enable', 'on')
            else
                set(findall(app.WeightPanel, '-property', 'Enable'),...
                'Enable', 'off')
            end
        end

        % Callback function: Eff_NormalDropDown, Eff_NormalDropDown
        function Eff_NormalDropDownValueChanged(app, event)
            value = app.Eff_NormalDropDown.Value;
            app.Data.Eff_Normal = evalin('base', value);
        end

        % Button pushed function: UpdateDropdownsButton
        function UpdateDropdownsButtonPushed(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
              cell_array{i} = vars(i).name;
            end
            app.TimesDropDown.Items = cell_array;
            app.FrictionDropDown.Items = cell_array;
            app.DisplacementmmDropDown.Items = cell_array;
            app.IndexDropDown.Items = cell_array;
            app.Eff_NormalDropDown.Items = cell_array;
        end

        % Button pushed function: ResetFiguresButton
        function ResetFiguresButtonPushed(app, event)
            cla(app.UIAxes_Stiffness)
        end

        % Button pushed function: SetStartofSteadyStateButton
        function SetStartofSteadyStateButtonPushed(app, event)
            % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                IofC = dcm_Info.DataIndex;
                datacursormode off
            app.PostLoadStartIndex = app.Data.Index(IofC);
            app.PostLoadingDisp.Value = num2str(app.Data.Disp(IofC));
            app.PostLoadingMu.Value = num2str(app.Data.Friction(IofC));
            plot(app.UIAxes_Stiffness, app.Data.Disp(IofC), app.Data.Friction(IofC), "Marker", "<")

        end

        % Button pushed function: SetEndofSteadyStateButton
        function SetEndofSteadyStateButtonPushed(app, event)
              % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                IofC = dcm_Info.DataIndex;
                datacursormode off
            app.PreUnloadIndex = app.Data.Index(IofC);
            app.UnloadStartDisp.Value = num2str(app.Data.Disp(IofC));
            app.UnloadStartMu.Value = num2str(app.Data.Friction(IofC)); 
            plot(app.UIAxes_Stiffness, app.Data.Disp(IofC), app.Data.Friction(IofC), "Marker", ">")

        end

        % Button pushed function: SetPoint1Button_2
        function SetPoint1Button_2Pushed(app, event)
                % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                IofC = dcm_Info.DataIndex;
                datacursormode off

                set(app.Point1SlipValue_2, 'Value', num2str(Position(1)));
                set(app.Point1MuValue_2, 'Value', num2str(Position(2)));
                set(app.Point1IndexValue_2, 'Value', num2str(app.Data.Index(IofC)));
        end

        % Button pushed function: SetPoint2Button_3
        function SetPoint2Button_3Pushed(app, event)
                % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                IofC = dcm_Info.DataIndex;
                datacursormode off

                set(app.Point2SlipValue_3, 'Value', num2str(Position(1)));
                set(app.Point2MuValue_3, 'Value', num2str(Position(2)));
                set(app.Point2IndexValue_3, 'Value', num2str(app.Data.Index(IofC)));
        end

        % Button pushed function: SetPoint2Button_2
        function SetPoint2Button_2Pushed(app, event)
                % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                IofC = dcm_Info.DataIndex;
                datacursormode off

                set(app.Point2SlipValue_2, 'Value', num2str(Position(1)));
                set(app.Point2MuValue_2, 'Value', num2str(Position(2)));
                set(app.Point2IndexValue_2, 'Value', num2str(app.Data.Index(IofC)));
        end

        % Button pushed function: SetPoint1Button_3
        function SetPoint1Button_3Pushed(app, event)
                % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                IofC = dcm_Info.DataIndex;
                datacursormode off

                set(app.Point1SlipValue_3, 'Value', num2str(Position(1)));
                set(app.Point1MuValue_3, 'Value', num2str(Position(2)));
                set(app.Point1IndexValue_3, 'Value', num2str(app.Data.Index(IofC)));
        end

        % Button pushed function: FitTrendButton_3
        function FitTrendButton_3Pushed(app, event)
            %%% Get the point locations.
                Point1_Index = str2double(get(app.Point1IndexValue_3,'Value'));
                Point2_Index = str2double(get(app.Point2IndexValue_3,'Value'));
            %%% Get the slip, friction, and time data.
                SlipData = app.Data.Disp;
                FrictionData = app.Data.Friction;
                IndexData = app.Data.Index;
                indices = (IndexData >= Point1_Index & IndexData <= Point2_Index);
            %%% See if there is a previous detrend line plotted and delete it.
                children = get(gcf, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Do the linear fit and plot the line on the main axes.
                p = polyfit(SlipData(indices),FrictionData(indices),1);
                hold(app.UIAxes_Stiffness,'on');
                plot(app.UIAxes_Stiffness,SlipData(indices),p(1)*SlipData(indices) + p(2),'r')
                hold(app.UIAxes_Stiffness,'off');
            %%% Display the latex_slope value in the GUI latex_slope box.
                app.SlopeValue_3.Value = num2str(p(1));
            %%% Store the zoomed slip and friction values, and the linear fit latex_slope
            %%% value.
                app.UnloadStiffness = p(1);
        end

        % Button pushed function: FitTrendButton_2
        function FitTrendButton_2Pushed(app, event)
                %%% Get the point locations.
                Point1_Index = str2double(get(app.Point1IndexValue_2,'Value'));
                Point2_Index = str2double(get(app.Point2IndexValue_2,'Value'));
            %%% Get the slip, friction, and time data.
                SlipData = app.Data.Disp;
                FrictionData = app.Data.Friction;
                IndexData = app.Data.Index;
                indices = (IndexData >= Point1_Index & IndexData <= Point2_Index);
            %%% See if there is a previous detrend line plotted and delete it.
                children = get(gcf, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Do the linear fit and plot the line on the main axes.
                p = polyfit(SlipData(indices),FrictionData(indices),1);
                hold(app.UIAxes_Stiffness,'on');
                plot(app.UIAxes_Stiffness,SlipData(indices),p(1)*SlipData(indices) + p(2),'r')
                hold(app.UIAxes_Stiffness,'off');
            %%% Display the latex_slope value in the GUI latex_slope box.
                set(app.SlopeValue_2, 'Value', num2str(p(1)));
            %%% Store the zoomed slip and friction values, and the linear fit latex_slope
            %%% value.
                app.LoadingStiffness = p(1);
        end

        % Button pushed function: ClearPointsButton_3
        function ClearPointsButton_3Pushed(app, event)
            %%% See if there is a previous detrend line plotted and delete it.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)   

                children = get(gcf, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Clear all the displayed and stored values from any previous detrend.
                set(app.Point1SlipValue_3, 'Value', '');
                set(app.Point1MuValue_3, 'Value', '');
                set(app.Point1IndexValue_3, 'Value', '');
                set(app.Point2SlipValue_3, 'Value', '');
                set(app.Point2MuValue_3, 'Value', '');
                set(app.Point2IndexValue_3, 'Value', '');
                set(app.SlopeValue_3, 'Value', '');
                app.LoadingStiffness = '';
        end

        % Button pushed function: ClearPointsButton_2
        function ClearPointsButton_2Pushed(app, event)
            %%% See if there is a previous detrend line plotted and delete it.
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)   

                children = get(gcf, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Clear all the displayed and stored values from any previous detrend.
                set(app.Point1SlipValue_2, 'Value', '');
                set(app.Point1MuValue_2, 'Value', '');
                set(app.Point1IndexValue_2, 'Value', '');
                set(app.Point2SlipValue_2, 'Value', '');
                set(app.Point2MuValue_2, 'Value', '');
                set(app.Point2IndexValue_2, 'Value', '');
                set(app.SlopeValue_2, 'Value', '');
                app.UnloadStiffness = '';
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            disp = app.Data.Disp;
            index = app.Data.Index;
            time = app.Data.Time;
            friction = app.Data.Friction;
            eff_normal = app.Data.Eff_Normal;
            PostLoadingIndex = app.PostLoadStartIndex;
            PreUnloadIndex = app.PreUnloadIndex;
            LoadingStiffness = app.LoadingStiffness;
            UnloadingStiffness = app.UnloadStiffness;
            
            save(app.FileName_Step1Output.Value + ".mat", "disp", "time", "friction", "eff_normal", "index", "PostLoadingIndex", "PreUnloadIndex", "UnloadingStiffness", "LoadingStiffness")
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            
            try
                load(app.OutputofStep1EditField.Value + ".mat", "export_UI_Table", "disp", "time", "friction", "eff_normal", "index", "PostLoadingIndex", "PreUnloadIndex", "LoadingStiffness", "UnloadingStiffness")
                app.UITable.Data = export_UI_Table;
                plot(app.UIAxes, disp(index >= PostLoadingIndex &index <= PreUnloadIndex), friction(index >= PostLoadingIndex &index <= PreUnloadIndex),'.')
                plot(app.UIAxes, disp, friction)
                hold(app.UIAxes,'on');
                plot(app.UIAxes, export_UI_Table(:,1), export_UI_Table(:,2), 'ro','MarkerFaceColor', 'r')
                plot(app.UIAxes2, time, disp)
                hold(app.UIAxes2,'on');
            catch
                load(app.OutputofStep1EditField.Value + ".mat", "disp", "time", "friction", "eff_normal", "index", "PostLoadingIndex", "PreUnloadIndex", "LoadingStiffness", "UnloadingStiffness")
                app.UITable.Data = [];
                plot(app.UIAxes, disp(index >= PostLoadingIndex &index <= PreUnloadIndex), friction(index >= PostLoadingIndex &index <= PreUnloadIndex),'.')
                hold(app.UIAxes,'on');
                plot(app.UIAxes, disp, friction)
                plot(app.UIAxes2, time, disp)
                hold(app.UIAxes2,'on');
            end

            app.Data.Time = time;
            app.Data.Disp = disp;
            app.Data.Friction = friction;
            app.Data.Eff_Normal = eff_normal;
            app.Data.Index = index;
            app.PostLoadStartIndex = PostLoadingIndex;
            app.PreUnloadIndex = PreUnloadIndex;
            app.LoadingStiffness = LoadingStiffness;
            app.UnloadStiffness = UnloadingStiffness;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            cn = app.RowNumberEditField.Value;
            app.UITable.Data(cn,:) = [];
        end

        % Button pushed function: AddFitToTableButton
        function AddFitToTableButtonPushed(app, event)
        %x_0_A x_f_A Slip_f_A Mu_f_A Covar_A x_0_A2 x_f_A2 Slip_f_A2 Mu_f_A2 Covar_A2 x_0_S x_f_S Slip_f_S Mu_f_S Covar_S x_0_S2 x_f_S2 Slip_f_S2 Mu_f_S2 Covar_S2
        
        event_num = app.StepNumber;
        app.Event(event_num).WeightInfo = app.WeightParameters;
        if ~isempty(fieldnames(app.AgingLawFitData))
            if ~isempty(app.UITable_Fit.Data)
                test = [table2array(cell2table(app.UITable_Fit.Data(:,1))) == event_num, table2array(cell2table(app.UITable_Fit.Data(:,9))) == 1, table2array(cell2table(app.UITable_Fit.Data(:,10))) == "Aging"];
                [~, repeat_index] = ismember([1,1,1],test,'rows');
                if repeat_index > 0
                    app.UITable_Fit.Data(repeat_index,:) = [event_num, app.AgingLawFitData.x_f(1), app.AgingLawFitData.x_f(2), app.AgingLawFitData.x_f(3), app.AgingLawFitData.x_f(6), app.AgingLawFitData.x_f(4), app.AgingLawFitData.x_f(7), app.AgingLawFitData.x_f(5), 1, {'Aging'}];
                else
                    app.UITable_Fit.Data(end+1,:) = [event_num, app.AgingLawFitData.x_f(1), app.AgingLawFitData.x_f(2), app.AgingLawFitData.x_f(3), app.AgingLawFitData.x_f(6), app.AgingLawFitData.x_f(4), app.AgingLawFitData.x_f(7), app.AgingLawFitData.x_f(5), 1, {'Aging'}];
                end
            else
                app.UITable_Fit.Data(end+1,:) = [event_num, app.AgingLawFitData.x_f(1), app.AgingLawFitData.x_f(2), app.AgingLawFitData.x_f(3), app.AgingLawFitData.x_f(6), app.AgingLawFitData.x_f(4), app.AgingLawFitData.x_f(7), app.AgingLawFitData.x_f(5), 1, {'Aging'}];
            end
            app.UITable_Fit.Data = sortrows(app.UITable_Fit.Data, 1);


            x_fEvent = app.AgingLawFitData.x_f;
            Event_Covar = app.AgingLawFitData.Covar_f;
            ExitEvent = app.AgingLawFitData.ExitFlag;
            EventError = app.AgingLawFitData.EventError;
            FrictionCOD = app.AgingLawFitData.FrictionCOD;
            GIC = app.AgingLawFitData.GIC;
            logL = app.AgingLawFitData.logL;
            app.Event(event_num).AgingLawParameters = struct('mu_0', [x_fEvent(1), EventError(1)],...
                    'a', [x_fEvent(2), EventError(2)], 'b1', [x_fEvent(3), EventError(3)],...
                    'b2', [x_fEvent(6), EventError(4)], 'aminusb', [x_fEvent(2) - x_fEvent(3)...
                    , EventError(8)], 'd_c1', [x_fEvent(4), EventError(5)],...
                    'd_c2', [x_fEvent(7), EventError(6)], 'stiffness', [x_fEvent(5),...
                    EventError(7)], 'ExitFlag', ExitEvent, 'R_Squared', FrictionCOD,...
                    'CovarianceMatrix', Event_Covar, 'GIC', GIC, 'logL', logL);
            
            Mu_f = app.AgingLawFitData.Mu_f;
            Slip_f = app.AgingLawFitData.Slip_f;
            m = app.Event(event_num).DetrendParameters.Slope;
            ReferencePoint = app.Event(event_num).EventInfo.StepDisp;
            if m == 0
                app.Event(event_num).AgingLawFit = [Slip_f, Mu_f];
            else
                Mu_f_Retrend = Mu_f - m*(ReferencePoint...
                    - Slip_f);
                app.Event(event_num).AgingLawFit = [Slip_f, Mu_f, Mu_f_Retrend];
            end
            [x_0, x_e, Vars_i] = GetGuesses_RunSlider(app, 'Aging', 1);
            app.Event(event_num).TrialAgingLawParameters = struct('mu_0', x_0(1), 'a', x_0(2), 'b1', x_0(3), 'b2', x_0(6), 'd_c1', x_0(4), 'd_c2', x_0(7), 'stiffness', x_0(5));
        end 
       
        if ~isempty(fieldnames(app.SlipLawFitData))
            if ~isempty(app.UITable_Fit.Data)
                test = [table2array(cell2table(app.UITable_Fit.Data(:,1))) == event_num, table2array(cell2table(app.UITable_Fit.Data(:,9))) == 1, table2array(cell2table(app.UITable_Fit.Data(:,10))) == "Slip"];
                [~, repeat_index] = ismember([1,1,1],test,'rows');
                if repeat_index > 0
                    app.UITable_Fit.Data(repeat_index,:) = [event_num, app.SlipLawFitData.x_f(1), app.SlipLawFitData.x_f(2), app.SlipLawFitData.x_f(3), app.SlipLawFitData.x_f(6), app.SlipLawFitData.x_f(4), app.SlipLawFitData.x_f(7), app.SlipLawFitData.x_f(5), 1, {'Slip'}];
                else
                    app.UITable_Fit.Data(end+1,:) = [event_num, app.SlipLawFitData.x_f(1), app.SlipLawFitData.x_f(2), app.SlipLawFitData.x_f(3), app.SlipLawFitData.x_f(6), app.SlipLawFitData.x_f(4), app.SlipLawFitData.x_f(7), app.SlipLawFitData.x_f(5), 1, {'Slip'}];
                end
            else
                app.UITable_Fit.Data(end+1,:) = [event_num, app.SlipLawFitData.x_f(1), app.SlipLawFitData.x_f(2), app.SlipLawFitData.x_f(3), app.SlipLawFitData.x_f(6), app.SlipLawFitData.x_f(4), app.SlipLawFitData.x_f(7), app.SlipLawFitData.x_f(5), 1, {'Slip'}];
            end  
            app.UITable_Fit.Data = sortrows(app.UITable_Fit.Data, 1);

            x_fEvent = app.SlipLawFitData.x_f;
            Event_Covar = app.SlipLawFitData.Covar_f;
            ExitEvent = app.SlipLawFitData.ExitFlag;
            EventError = app.SlipLawFitData.EventError;
            FrictionCOD = app.SlipLawFitData.FrictionCOD;
            GIC = app.SlipLawFitData.GIC;
            logL = app.SlipLawFitData.logL;
            app.Event(event_num).SlipLawParameters = struct('mu_0', [x_fEvent(1), EventError(1)],...
                    'a', [x_fEvent(2), EventError(2)], 'b1', [x_fEvent(3), EventError(3)],...
                    'b2', [x_fEvent(6), EventError(4)], 'aminusb', [x_fEvent(2) - x_fEvent(3)...
                    , EventError(8)], 'd_c1', [x_fEvent(4), EventError(5)],...
                    'd_c2', [x_fEvent(7), EventError(6)], 'stiffness', [x_fEvent(5),...
                    EventError(7)], 'ExitFlag', ExitEvent, 'R_Squared', FrictionCOD,...
                    'CovarianceMatrix', Event_Covar, 'GIC', GIC, 'logL', logL);
            
            Mu_f = app.SlipLawFitData.Mu_f;
            Slip_f = app.SlipLawFitData.Slip_f;
            m = app.Event(event_num).DetrendParameters.Slope;
            ReferencePoint = app.Event(event_num).EventInfo.StepDisp;
            if m == 0
                app.Event(event_num).SlipLawFit = [Slip_f, Mu_f];
            else
                Mu_f_Retrend = Mu_f - m*(ReferencePoint...
                    - Slip_f);
                app.Event(event_num).SlipLawFit = [Slip_f, Mu_f, Mu_f_Retrend];
            end
            [x_0, ~, ~] = GetGuesses_RunSlider(app, 'Slip', 1);
            app.Event(event_num).TrialSlipLawParameters = struct('mu_0', x_0(1), 'a', x_0(2), 'b1', x_0(3), 'b2', x_0(6), 'd_c1', x_0(4), 'd_c2', x_0(7), 'stiffness', x_0(5));
        end
        
        if ~isempty(fieldnames(app.AgingLawFitData_2State))
            if ~isempty(app.UITable_Fit.Data)
                test = [table2array(cell2table(app.UITable_Fit.Data(:,1))) == event_num, table2array(cell2table(app.UITable_Fit.Data(:,9))) == 2, table2array(cell2table(app.UITable_Fit.Data(:,10))) == "Aging"];
                [~, repeat_index] = ismember([1,1,1],test,'rows');
                if repeat_index > 0
                    app.UITable_Fit.Data(repeat_index,:) = [event_num, app.AgingLawFitData_2State.x_f(1), app.AgingLawFitData_2State.x_f(2), app.AgingLawFitData_2State.x_f(3), app.AgingLawFitData_2State.x_f(6), app.AgingLawFitData_2State.x_f(4), app.AgingLawFitData_2State.x_f(7), app.AgingLawFitData_2State.x_f(5), 2, {'Aging'}];
                else
                    app.UITable_Fit.Data(end+1,:) = [event_num, app.AgingLawFitData_2State.x_f(1), app.AgingLawFitData_2State.x_f(2), app.AgingLawFitData_2State.x_f(3), app.AgingLawFitData_2State.x_f(6), app.AgingLawFitData_2State.x_f(4), app.AgingLawFitData_2State.x_f(7), app.AgingLawFitData_2State.x_f(5), 2, {'Aging'}];
                end
            else
                app.UITable_Fit.Data(end+1,:) = [event_num, app.AgingLawFitData_2State.x_f(1), app.AgingLawFitData_2State.x_f(2), app.AgingLawFitData_2State.x_f(3), app.AgingLawFitData_2State.x_f(6), app.AgingLawFitData_2State.x_f(4), app.AgingLawFitData_2State.x_f(7), app.AgingLawFitData_2State.x_f(5), 2, {'Aging'}];
            end
            x_fEvent = app.AgingLawFitData_2State.x_f;
            Event_Covar = app.AgingLawFitData_2State.Covar_f;
            ExitEvent = app.AgingLawFitData_2State.ExitFlag;
            EventError = app.AgingLawFitData_2State.EventError;
            FrictionCOD = app.AgingLawFitData_2State.FrictionCOD;
            GIC = app.AgingLawFitData_2State.GIC;
            logL = app.AgingLawFitData_2State.logL;
            app.Event(event_num).AgingLawParameters_2State = struct('mu_0', [x_fEvent(1), EventError(1)],...
                    'a', [x_fEvent(2), EventError(2)], 'b1', [x_fEvent(3), EventError(3)],...
                    'b2', [x_fEvent(6), EventError(4)], 'aminusb', [x_fEvent(2) - x_fEvent(3)...
                    - x_fEvent(6), EventError(8)], 'd_c1', [x_fEvent(4), EventError(5)],...
                    'd_c2', [x_fEvent(7), EventError(6)], 'stiffness', [x_fEvent(5),...
                    EventError(7)], 'ExitFlag', ExitEvent, 'R_Squared', FrictionCOD,...
                    'CovarianceMatrix', Event_Covar, 'GIC', GIC, 'logL', logL);

            Mu_f = app.AgingLawFitData_2State.Mu_f;
            Slip_f = app.AgingLawFitData_2State.Slip_f;
            m = app.Event(event_num).DetrendParameters.Slope;
            ReferencePoint = app.Event(event_num).EventInfo.StepDisp;
            if m == 0
                app.Event(event_num).AgingLawFitData_2State = [Slip_f, Mu_f];
            else
                Mu_f_Retrend = Mu_f - m*(ReferencePoint...
                    - Slip_f);
                app.Event(event_num).AgingLawFitData_2State = [Slip_f, Mu_f, Mu_f_Retrend];
            end
            [x_0, ~, ~] = GetGuesses_RunSlider(app, 'Aging', 2);
            app.Event(event_num).TrialAgingLawParameters_2State = struct('mu_0', x_0(1), 'a', x_0(2), 'b1', x_0(3), 'b2', x_0(6), 'd_c1', x_0(4), 'd_c2', x_0(7), 'stiffness', x_0(5));
        end

        if ~isempty(fieldnames(app.SlipLawFitData_2State))
            if ~isempty(app.UITable_Fit.Data)
                test = [table2array(cell2table(app.UITable_Fit.Data(:,1))) == event_num, table2array(cell2table(app.UITable_Fit.Data(:,9))) == 2, table2array(cell2table(app.UITable_Fit.Data(:,10))) == "Slip"];
                [~, repeat_index] = ismember([1,1,1],test,'rows');
                if repeat_index > 0
                    app.UITable_Fit.Data(repeat_index,:) = [event_num, app.SlipLawFitData_2State.x_f(1), app.SlipLawFitData_2State.x_f(2), app.SlipLawFitData_2State.x_f(3), app.SlipLawFitData_2State.x_f(6), app.SlipLawFitData_2State.x_f(4), app.SlipLawFitData_2State.x_f(7), app.SlipLawFitData_2State.x_f(5), 2, {'Slip'}];
                else
                    app.UITable_Fit.Data(end+1,:) = [event_num, app.SlipLawFitData_2State.x_f(1), app.SlipLawFitData_2State.x_f(2), app.SlipLawFitData_2State.x_f(3), app.SlipLawFitData_2State.x_f(6), app.SlipLawFitData_2State.x_f(4), app.SlipLawFitData_2State.x_f(7), app.SlipLawFitData_2State.x_f(5), 2, {'Slip'}];
                end
            else
                app.UITable_Fit.Data(end+1,:) = [event_num, app.SlipLawFitData_2State.x_f(1), app.SlipLawFitData_2State.x_f(2), app.SlipLawFitData_2State.x_f(3), app.SlipLawFitData_2State.x_f(6), app.SlipLawFitData_2State.x_f(4), app.SlipLawFitData_2State.x_f(7), app.SlipLawFitData_2State.x_f(5), 2, {'Slip'}];
            end        
            
            x_fEvent = app.SlipLawFitData_2State.x_f;
            Event_Covar = app.SlipLawFitData_2State.Covar_f;
            ExitEvent = app.SlipLawFitData_2State.ExitFlag;
            EventError = app.SlipLawFitData_2State.EventError;
            FrictionCOD = app.SlipLawFitData_2State.FrictionCOD;
            GIC = app.SlipLawFitData_2State.GIC;
            logL = app.SlipLawFitData_2State.logL;
            app.Event(event_num).SlipLawParameters_2State = struct('mu_0', [x_fEvent(1), EventError(1)],...
                    'a', [x_fEvent(2), EventError(2)], 'b1', [x_fEvent(3), EventError(3)],...
                    'b2', [x_fEvent(6), EventError(4)], 'aminusb', [x_fEvent(2) - x_fEvent(3)...
                    - x_fEvent(6), EventError(8)], 'd_c1', [x_fEvent(4), EventError(5)],...
                    'd_c2', [x_fEvent(7), EventError(6)], 'stiffness', [x_fEvent(5),...
                    EventError(7)], 'ExitFlag', ExitEvent, 'R_Squared', FrictionCOD,...
                    'CovarianceMatrix', Event_Covar,'GIC', GIC, 'logL', logL);

            Mu_f = app.SlipLawFitData_2State.Mu_f;
            Slip_f = app.SlipLawFitData_2State.Slip_f;
            m = app.Event(event_num).DetrendParameters.Slope;
            ReferencePoint = app.Event(event_num).EventInfo.StepDisp;
            if m == 0
                app.Event(event_num).SlipLawFit_2State = [Slip_f, Mu_f];
            else
                Mu_f_Retrend = Mu_f - m*(ReferencePoint...
                    - Slip_f);
                app.Event(event_num).SlipLawFit_2State = [Slip_f, Mu_f, Mu_f_Retrend];
            end
            [x_0, ~, ~] = GetGuesses_RunSlider(app, 'Slip', 2);
            app.Event(event_num).TrialSlipLawParameters_2State = struct('mu_0', x_0(1), 'a', x_0(2), 'b1', x_0(3), 'b2', x_0(6), 'd_c1', x_0(4), 'd_c2', x_0(7), 'stiffness', x_0(5));
        end    
        end

        % Button pushed function: SaveFitsButton
        function SaveFitsButtonPushed(app, event)
            Events = app.Event;
            time = app.Data_forFit.Time;            
            disp = app.Data_forFit.Disp;
            friction = app.Data_forFit.Friction; 
            eff_normal = app.Data_forFit.Eff_Normal;
            index = app.Data_forFit.Index;
            UnloadingStiffness = app.UnloadStiffness;
            LoadingStiffness = app.LoadingStiffness;
            PreUnloadIndex = app.PreUnloadIndex;
            PostLoadingIndex = app.PostLoadStartIndex;
            UITable_Fit = app.UITable_Fit;

            save(app.SaveFileNameEditField_2.Value + ".mat", "UITable_Fit", "Events", "disp", "time", "friction", "eff_normal", "index", "UnloadingStiffness", "LoadingStiffness", "PreUnloadIndex", "PostLoadingIndex");
        end

        % Button pushed function: WeightLocButton
        function WeightLocButtonPushed(app, event)
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                
                WeightStartSlip = Position(1);
                WeightStartFriction = Position(2);
                app.WeightSlipValue.Value =  num2str(WeightStartSlip);
                app.WeightFrictionValue.Value = num2str(WeightStartFriction);
            %%% Store slip value for detrending later.
            %%% Calculate the s and plot in a new window.
                [Weight, WeightInfo, Slip_ZeroRef] = WeightCalc(app);
                semilogy(app.WeightAxes, Slip_ZeroRef, Weight, 'k', 'LineWidth', 2);
                %xlabel('Load Point Displacement (\mum)');
                %ylabel('Weight (N / i_w)^p');
                title(app.WeightAxes, ['N = ', num2str(WeightInfo(1)), ' total data points, i_w = ', num2str(WeightInfo(2)),...
                    ', p = ', num2str(WeightInfo(3))]);
        end

        % Button pushed function: LoadTableButton_Global
        function LoadTableButton_GlobalPushed(app, event)
            % When load table button is pushed then the file listed in the
            % file name box is loaded, which should include the output of
            % the Window and Detrend Process
            try
                load(app.EventFileNameEditField_Global.Value + ".mat", "export_UI_Table_2");
                load(app.EventFileNameEditField_Global.Value + ".mat", "Event");
                app.SingleEventDropDown.Items = "Event " + (1:height(export_UI_Table_2));
                load(app.EventFileNameEditField_Global.Value + ".mat", "time", "disp", "friction", "eff_normal", "index", "LoadingStiffness", "UnloadingStiffness", "PreUnloadIndex", "PostLoadingIndex");
                app.Event = Event;
                app.UITable_GlobalFit.Data = {};
            catch
                load(app.EventFileNameEditField_Global.Value + ".mat", "Events", "time", "disp", "friction", "eff_normal", "index", "LoadingStiffness", "UnloadingStiffness","PreUnloadIndex", "PostLoadingIndex");
                app.SingleEventDropDown.Items = "Event " + (1:length(Events));
                app.Event = Events;
            end
            
            app.UnloadStiffness = UnloadingStiffness;
            app.LoadingStiffness = LoadingStiffness;

            plot(app.UIAxes_FullExperiment, disp, friction)
            plot(app.UIAxes_SingleStep, app.Event(1).LoadPointDisplacementData, app.Event(1).FrictionDataDetrended)

            app.PreUnloadIndex = PreUnloadIndex;
            app.PostLoadStartIndex = PostLoadingIndex;
            app.LoadingStiffness1mEditField_2.Value = num2str(LoadingStiffness);
            app.UnLoadingStiffness1mEditField_2.Value = num2str(UnloadingStiffness);
            app.LocNormStress_2.Value = num2str(mean(eff_normal(index > PostLoadingIndex & index < PreUnloadIndex)));
            %app.UITable_2.Data = [[1:length(export_UI_Table_2.Data)]',export_UI_Table_2.Data, NaN+ zeros([length(export_UI_Table_2.Data),3])];
        end

        % Value changed function: SingleEventDropDown
        function SingleEventDropDownValueChanged(app, event)
            step_num = str2num(app.SingleEventDropDown.Value(7:end));
            step_disp = app.Event(step_num).LoadPointDisplacementData;
            step_friction = app.Event(step_num).FrictionDataDetrended;

            plot(app.UIAxes_SingleStep, step_disp, step_friction)
        end

        % Value changed function: Guess_dc2Value_a2_Global
        function Guess_dc2Value_a2_GlobalValueChanged(app, event)
            value = app.Guess_dc2Value_a2_Global.Value;
            app.Guess_dc2Value_s2_Global.Value = value;
        end

        % Value changed function: Guess_b2Value_a2_Global
        function Guess_b2Value_a2_GlobalValueChanged(app, event)
            value = app.Guess_b2Value_a2_Global.Value;
            app.Guess_b2Value_s2_Global.Value = value;
        end

        % Value changed function: Guess_kValue_a2_Global
        function Guess_kValue_a2_GlobalValueChanged(app, event)
            value = app.Guess_kValue_a2_Global.Value;
            app.Guess_kValue_s2_Global.Value = value;
        end

        % Value changed function: Guess_dcValue_a2_Global
        function Guess_dcValue_a2_GlobalValueChanged(app, event)
            value = app.Guess_dcValue_a2_Global.Value;
            app.Guess_dcValue_s2_Global.Value = value;
        end

        % Value changed function: Guess_bValue_a2_Global
        function Guess_bValue_a2_GlobalValueChanged(app, event)
            value = app.Guess_bValue_a2_Global.Value;
            app.Guess_bValue_s2_Global.Value = value;
        end

        % Value changed function: Guess_aValue_a2_Global
        function Guess_aValue_a2_GlobalValueChanged(app, event)
            value = app.Guess_aValue_a2_Global.Value;
            app.Guess_aValue_s2_Global.Value = value;
        end

        % Value changed function: Guess_kValue_a1_Global
        function Guess_kValue_a1_GlobalValueChanged(app, event)
            value = app.Guess_kValue_a1_Global.Value;
            app.Guess_kValue_s1_Global.Value = value;
        end

        % Value changed function: Guess_dcValue_a1_Global
        function Guess_dcValue_a1_GlobalValueChanged(app, event)
            value = app.Guess_dcValue_a1_Global.Value;
            app.Guess_dcValue_s1_Global.Value = value;
        end

        % Value changed function: Guess_bValue_a1_Global
        function Guess_bValue_a1_GlobalValueChanged(app, event)
            value = app.Guess_bValue_a1_Global.Value;
            app.Guess_bValue_s1_Global.Value = value;
        end

        % Value changed function: Guess_aValue_a1_Global
        function Guess_aValue_a1_GlobalValueChanged(app, event)
            value = app.Guess_aValue_a1_Global.Value;
            app.Guess_aValue_s1_Global.Value = value;
        end

        % Value changed function: SlipCheckBox_Global
        function SlipCheckBox_GlobalValueChanged(app, event)
            value = app.SlipCheckBox_Global.Value;
            if value
                if app.SlipTwoStateGuessButton_Global.Value
                    set(findall(app.SlipFittingPanel_Global, '-property', 'Enable'), 'Enable', 'on')
                else
                    set(findall(app.SlipFittingPanel_Global, '-property', 'Enable'), 'Enable', 'on')
                    set(app.Guess_dc2Value_s2_Global, 'Enable', 'off')
                    set(app.Guess_aValue_s2_Global, 'Enable', 'off')
                    set(app.Guess_b2Value_s2_Global, 'Enable', 'off')
                    set(app.Guess_bValue_s2_Global, 'Enable', 'off')
                    set(app.Guess_dcValue_s2_Global, 'Enable', 'off')
                    set(app.Guess_kValue_s2_Global, 'Enable', 'off')
                end
            else
                set(findall(app.SlipFittingPanel_Global, '-property', 'Enable'),...
                'Enable', 'off')
            end       
        end

        % Value changed function: AgingCheckBox_Global
        function AgingCheckBox_GlobalValueChanged(app, event)
            value = app.AgingCheckBox_Global.Value;
            if value
                if app.AgingTwoStateGuessButton_Global.Value
                    set(findall(app.AgingFittingPanel_Global, '-property', 'Enable'), 'Enable', 'on')
                else
                    set(findall(app.AgingFittingPanel_Global, '-property', 'Enable'), 'Enable', 'on')
                    set(app.Guess_dc2Value_a2_Global, 'Enable', 'off')
                    set(app.Guess_aValue_a2_Global, 'Enable', 'off')
                    set(app.Guess_b2Value_a2_Global, 'Enable', 'off')
                    set(app.Guess_bValue_a2_Global, 'Enable', 'off')
                    set(app.Guess_dcValue_a2_Global, 'Enable', 'off')
                    set(app.Guess_kValue_a2_Global, 'Enable', 'off')
                end
            else
                set(findall(app.AgingFittingPanel_Global, '-property', 'Enable'),...
                'Enable', 'off')
            end       
        end

        % Value changed function: SlipTwoStateGuessButton_Global
        function SlipTwoStateGuessButton_GlobalValueChanged(app, event)
            value = app.SlipTwoStateGuessButton_Global.Value;
            if value
                set(app.Guess_dc2Value_s2_Global, 'Enable', 'on')
                set(app.Guess_aValue_s2_Global, 'Enable', 'on')
                set(app.Guess_b2Value_s2_Global, 'Enable', 'on')
                set(app.Guess_bValue_s2_Global, 'Enable', 'on')
                set(app.Guess_dcValue_s2_Global, 'Enable', 'on')
                set(app.Guess_kValue_s2_Global, 'Enable', 'on')
            else
                set(app.Guess_dc2Value_s2_Global, 'Enable', 'off')
                set(app.Guess_aValue_s2_Global, 'Enable', 'off')
                set(app.Guess_b2Value_s2_Global, 'Enable', 'off')
                set(app.Guess_bValue_s2_Global, 'Enable', 'off')
                set(app.Guess_dcValue_s2_Global, 'Enable', 'off')
                set(app.Guess_kValue_s2_Global, 'Enable', 'off')
            end       
        end

        % Value changed function: AgingTwoStateGuessButton_Global
        function AgingTwoStateGuessButton_GlobalValueChanged(app, event)
            value = app.AgingTwoStateGuessButton_Global.Value;
            if value
                set(app.Guess_dc2Value_a2_Global, 'Enable', 'on')
                set(app.Guess_aValue_a2_Global, 'Enable', 'on')
                set(app.Guess_b2Value_a2_Global, 'Enable', 'on')
                set(app.Guess_bValue_a2_Global, 'Enable', 'on')
                set(app.Guess_dcValue_a2_Global, 'Enable', 'on')
                set(app.Guess_kValue_a2_Global, 'Enable', 'on')
            else
                set(app.Guess_dc2Value_a2_Global, 'Enable', 'off')
                set(app.Guess_aValue_a2_Global, 'Enable', 'off')
                set(app.Guess_b2Value_a2_Global, 'Enable', 'off')
                set(app.Guess_bValue_a2_Global, 'Enable', 'off')
                set(app.Guess_dcValue_a2_Global, 'Enable', 'off')
                set(app.Guess_kValue_a2_Global, 'Enable', 'off')
            end       
        end

        % Button pushed function: PickSlideHoldSlideButton
        function PickSlideHoldSlideButtonPushed(app, event)
            % HERE FOR CLICKING
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                IofC = dcm_Info.DataIndex;
                %pick_disp = dcm_Info.Position(1);
                %pick_friction = dcm_Info.Position(2);
                datacursormode off

            temp_indices = [1:length(app.Data.Disp)]';
            %IofC = temp_indices(app.Data.Disp == pick_disp & app.Data.Friction == pick_friction);
            %IofC = IofC(1);

            dist = abs(app.Data.Time - (app.Data.Time(IofC) + str2num(app.HoldTimesEditField.Value)));
            minDist = min(dist);
            EndOfHoldIndex = temp_indices(dist == minDist);
            min_index = min(app.Data.Index(app.Data.Disp>=(app.Data.Disp(IofC)-10) & app.Data.Index > app.PostLoadStartIndex));
            max_index = max(app.Data.Index(app.Data.Disp<=(app.Data.Disp(EndOfHoldIndex)+10) & app.Data.Index < app.PreUnloadIndex));

            pick_index = app.Data.Index(IofC);
            pick_time = app.Data.Time(IofC);
            pick_disp = app.Data.Disp(IofC);
            pick_friction = app.Data.Friction(IofC);
            end_friction = app.Data.Friction(EndOfHoldIndex);
            end_index = app.Data.Index(EndOfHoldIndex);
            end_time = app.Data.Time(EndOfHoldIndex);
            end_disp = app.Data.Disp(EndOfHoldIndex);
            
            v0_indices = (temp_indices < IofC & app.Data.Disp > app.Data.Disp(IofC)-100 & app.Data.Index < max_index & app.Data.Index > app.PostLoadStartIndex);
            vf_indices = (app.Data.Disp < app.Data.Disp(EndOfHoldIndex)+100 & temp_indices > EndOfHoldIndex & app.Data.Index < app.PreUnloadIndex);
            v0 = diff(app.Data.Disp(v0_indices))./diff(app.Data.Time(v0_indices));
            vf = diff(app.Data.Disp(vf_indices))./diff(app.Data.Time(vf_indices));


            app.UIFigure.HandleVisibility = fhv;        % return original state

            plot(app.UIAxes, pick_disp, pick_friction,'ro', 'MarkerFaceColor', 'r') 
            plot(app.UIAxes, end_disp, end_friction,'ko', 'MarkerFaceColor', 'k') 
            plot(app.UIAxes2, pick_time, pick_disp, 'ro', 'MarkerFaceColor', 'r') 
            plot(app.UIAxes2, end_time, end_disp, 'ko', 'MarkerFaceColor', 'k') 
            ylim(app.UIAxes2, [app.Data.Disp(app.Data.Index == min_index), app.Data.Disp(app.Data.Index == max_index)])
            xlim(app.UIAxes2, [app.Data.Time(app.Data.Index == min_index), app.Data.Time(app.Data.Index == max_index)])
            app.ExportData.pick_time = pick_time;
            app.ExportData.pick_disp = pick_disp;
            app.ExportData.pick_index = pick_index;
            app.ExportData.pick_friction = pick_friction;
            app.ExportData.v0 = round(mean(v0), app.VelocitySigFigEditField.Value, 'significant');
            app.ExportData.vf = round(mean(vf), app.VelocitySigFigEditField.Value, 'significant');
            app.ExportData.HoldTime = str2num(app.HoldTimesEditField.Value);
        end

        % Value changed function: TestButton_Global
        function TestButton_GlobalValueChanged(app, event)
            value = app.TestButton_Global.Value;
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)

            %%% See if there's any previous lines plotted and delete them.
            %children = get(app.FittingAxes, 'children');
            %if numel(children) > 1
            %    delete(children(1:end-1));
            %end
            
            %%% Get the stuff.
            %type of events to include
            events_all = [1:length(app.Event)]; 
            events_include = []; % events to fit
            for event_num = events_all
                if app.SHS_Button.Value & ~isnan(app.Event(event_num).EventInfo.HoldTime)
                    events_include = [events_include, event_num];
                end
                if app.VS_Up_Button.Value & isnan(app.Event(event_num).EventInfo.HoldTime) & app.Event(event_num).EventInfo.V0 < app.Event(event_num).EventInfo.VF
                    events_include = [events_include, event_num];
                end
                if app.VS_Up_Button.Value & isnan(app.Event(event_num).EventInfo.HoldTime) & app.Event(event_num).EventInfo.V0 > app.Event(event_num).EventInfo.VF
                    events_include = [events_include, event_num];
                end
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Step1LoadingandUnloadingStiffnessTab = uitab(app.TabGroup);
            app.Step1LoadingandUnloadingStiffnessTab.Title = 'Step 1: Loading and Unloading Stiffness';
            app.UIAxes_Stiffness = uiaxes(app.Step1LoadingandUnloadingStiffnessTab);
            xlabel(app.UIAxes_Stiffness, 'Displacement (μm)')
            ylabel(app.UIAxes_Stiffness, 'Friction, μ')
            app.PlotDataButton = uibutton(app.Step1LoadingandUnloadingStiffnessTab, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.TimesDropDown = uidropdown(app.Step1LoadingandUnloadingStiffnessTab);
            app.TimesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TimesDropDownValueChanged, true);
            app.TimesDropDown.ValueChangedFcn = createCallbackFcn(app, @TimesDropDownValueChanged, true);
            app.DisplacementmmDropDown = uidropdown(app.Step1LoadingandUnloadingStiffnessTab);
            app.DisplacementmmDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DisplacementmmDropDownOpening, true);
            app.DisplacementmmDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplacementmmDropDownOpening, true);
            app.FrictionDropDown = uidropdown(app.Step1LoadingandUnloadingStiffnessTab);
            app.FrictionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FrictionDropDownValueChanged, true);
            app.FrictionDropDown.ValueChangedFcn = createCallbackFcn(app, @FrictionDropDownValueChanged, true);
            app.IndexDropDown = uidropdown(app.Step1LoadingandUnloadingStiffnessTab);
            app.IndexDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndexDropDownValueChanged, true);
            app.IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @IndexDropDownValueChanged, true);
            app.TimesDropDownLabel = uilabel(app.Step1LoadingandUnloadingStiffnessTab);
            app.TimesDropDownLabel.Text = 'Time (s)';
            app.DisplacementmmDropDownLabel = uilabel(app.Step1LoadingandUnloadingStiffnessTab);
            app.DisplacementmmDropDownLabel.Text = 'Displacement (μm)';
            app.FrictionDropDownLabel = uilabel(app.Step1LoadingandUnloadingStiffnessTab);
            app.FrictionDropDownLabel.Text = 'Friction';
            app.IndexDropDownLabel = uilabel(app.Step1LoadingandUnloadingStiffnessTab);
            app.IndexDropDownLabel.Text = 'Index';
            app.Eff_NormalDropDown = uidropdown(app.Step1LoadingandUnloadingStiffnessTab);
            app.Eff_NormalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @Eff_NormalDropDownValueChanged, true);
            app.Eff_NormalDropDown.ValueChangedFcn = createCallbackFcn(app, @Eff_NormalDropDownValueChanged, true);
            app.Eff_NormalDropDownLabel = uilabel(app.Step1LoadingandUnloadingStiffnessTab);
            app.Eff_NormalDropDownLabel.Text = 'Normal Stress (MPa)';
            app.UpdateDropdownsButton = uibutton(app.Step1LoadingandUnloadingStiffnessTab, 'push');
            app.UpdateDropdownsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDropdownsButtonPushed, true);
            app.UpdateDropdownsButton.Text = 'Update Dropdowns';
            app.ResetFiguresButton = uibutton(app.Step1LoadingandUnloadingStiffnessTab, 'push');
            app.ResetFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFiguresButtonPushed, true);
            app.ResetFiguresButton.Text = 'Reset Figures';
            app.UnloadingPanel = uipanel(app.Step1LoadingandUnloadingStiffnessTab);
            app.UnloadingPanel.Title = 'Unloading Stiffness';
            app.Point1SlipValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.SlopeValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.latex_Slope_3 = uilabel(app.UnloadingPanel);
            app.latex_Slope_3.Text = 'Unloading Stiffness (1 / μm)';
            app.Point2SlipValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.Point1MuValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.Point2MuValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.SetPoint1Button_3 = uibutton(app.UnloadingPanel, 'push');
            app.SetPoint1Button_3.ButtonPushedFcn = createCallbackFcn(app, @SetPoint1Button_3Pushed, true);
            app.SetPoint1Button_3.Text = 'Set Point 1';
            app.FitTrendButton_3 = uibutton(app.UnloadingPanel, 'push');
            app.FitTrendButton_3.ButtonPushedFcn = createCallbackFcn(app, @FitTrendButton_3Pushed, true);
            app.FitTrendButton_3.Text = 'Fit Trend';
            app.text47_3 = uilabel(app.UnloadingPanel);
            app.text47_3.Text = 'friction';
            app.text48_3 = uilabel(app.UnloadingPanel);
            app.text48_3.Text = 'displacement';
            app.text49_3 = uilabel(app.UnloadingPanel);
            app.text49_3.Text = 'friction';
            app.text50_3 = uilabel(app.UnloadingPanel);
            app.text50_3.Text = 'displacement';
            app.SetPoint2Button_3 = uibutton(app.UnloadingPanel, 'push');
            app.SetPoint2Button_3.ButtonPushedFcn = createCallbackFcn(app, @SetPoint2Button_3Pushed, true);
            app.SetPoint2Button_3.Text = 'Set Point 2';
            app.ClearPointsButton_3 = uibutton(app.UnloadingPanel, 'push');
            app.ClearPointsButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButton_3Pushed, true);
            app.ClearPointsButton_3.Text = 'Clear Points';
            app.text48_8 = uilabel(app.UnloadingPanel);
            app.text48_8.Text = 'index';
            app.Point2IndexValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.text48_9 = uilabel(app.UnloadingPanel);
            app.text48_9.Text = 'index';
            app.Point1IndexValue_3 = uieditfield(app.UnloadingPanel, 'text');
            app.text55_2 = uilabel(app.UnloadingPanel);
            app.text55_2.Text = 'Set stiffness to zero if unloading curve is not recorded.';
            app.LoadingPanel = uipanel(app.Step1LoadingandUnloadingStiffnessTab);
            app.LoadingPanel.Title = 'Loading Stiffness';
            app.Point1SlipValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.SlopeValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.latex_Slope_2 = uilabel(app.LoadingPanel);
            app.latex_Slope_2.Text = 'Loading Stiffness (1 / μm)';
            app.Point2SlipValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.Point1MuValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.Point2MuValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.SetPoint1Button_2 = uibutton(app.LoadingPanel, 'push');
            app.SetPoint1Button_2.ButtonPushedFcn = createCallbackFcn(app, @SetPoint1Button_2Pushed, true);
            app.SetPoint1Button_2.Text = 'Set Point 1';
            app.FitTrendButton_2 = uibutton(app.LoadingPanel, 'push');
            app.FitTrendButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitTrendButton_2Pushed, true);
            app.FitTrendButton_2.Text = 'Fit Trend';
            app.text47_2 = uilabel(app.LoadingPanel);
            app.text47_2.Text = 'friction';
            app.text48_2 = uilabel(app.LoadingPanel);
            app.text48_2.Text = 'displacement';
            app.text49_2 = uilabel(app.LoadingPanel);
            app.text49_2.Text = 'friction';
            app.text50_2 = uilabel(app.LoadingPanel);
            app.text50_2.Text = 'displacement';
            app.SetPoint2Button_2 = uibutton(app.LoadingPanel, 'push');
            app.SetPoint2Button_2.ButtonPushedFcn = createCallbackFcn(app, @SetPoint2Button_2Pushed, true);
            app.SetPoint2Button_2.Text = 'Set Point 2';
            app.ClearPointsButton_2 = uibutton(app.LoadingPanel, 'push');
            app.ClearPointsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButton_2Pushed, true);
            app.ClearPointsButton_2.Text = 'Clear Points';
            app.text48_6 = uilabel(app.LoadingPanel);
            app.text48_6.Text = 'index';
            app.Point2IndexValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.text48_7 = uilabel(app.LoadingPanel);
            app.text48_7.Text = 'index';
            app.Point1IndexValue_2 = uieditfield(app.LoadingPanel, 'text');
            app.text55_3 = uilabel(app.LoadingPanel);
            app.text55_3.Text = 'Set stiffness to zero if loading curve is not recorded.';
            app.StartandEndofSteadyStateShearPanel = uipanel(app.Step1LoadingandUnloadingStiffnessTab);
            app.StartandEndofSteadyStateShearPanel.Title = 'Start and End of Steady-State Shear';
            app.SetStartofSteadyStateButton = uibutton(app.StartandEndofSteadyStateShearPanel, 'push');
            app.SetStartofSteadyStateButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartofSteadyStateButtonPushed, true);
            app.SetStartofSteadyStateButton.Text = 'Set Start of Steady-State';
            app.SetEndofSteadyStateButton = uibutton(app.StartandEndofSteadyStateShearPanel, 'push');
            app.SetEndofSteadyStateButton.ButtonPushedFcn = createCallbackFcn(app, @SetEndofSteadyStateButtonPushed, true);
            app.SetEndofSteadyStateButton.Text = 'Set End of Steady-State';
            app.thepointbeforeunloadingLabel = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.thepointbeforeunloadingLabel.Text = '(the point before unloading)';
            app.thepointwhenloadingiscompleteLabel = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.thepointwhenloadingiscompleteLabel.Text = '(the point when loading is complete)';
            app.PostLoadingDisp = uieditfield(app.StartandEndofSteadyStateShearPanel, 'text');
            app.PostLoadingMu = uieditfield(app.StartandEndofSteadyStateShearPanel, 'text');
            app.text47_4 = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.text47_4.Text = 'friction';
            app.text48_4 = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.text48_4.Text = 'displacement';
            app.UnloadStartDisp = uieditfield(app.StartandEndofSteadyStateShearPanel, 'text');
            app.UnloadStartMu = uieditfield(app.StartandEndofSteadyStateShearPanel, 'text');
            app.text47_5 = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.text47_5.Text = 'friction';
            app.text48_5 = uilabel(app.StartandEndofSteadyStateShearPanel);
            app.text48_5.Text = 'displacement';
            app.ClearPointsButton_4 = uibutton(app.StartandEndofSteadyStateShearPanel, 'push');
            app.ClearPointsButton_4.Text = 'Clear Points';
            app.FileName_Step1Output = uieditfield(app.Step1LoadingandUnloadingStiffnessTab, 'text');
            app.SaveOutputButton = uibutton(app.Step1LoadingandUnloadingStiffnessTab, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'Save Output';
            app.Step2PickEventsTab = uitab(app.TabGroup);
            app.Step2PickEventsTab.Title = 'Step 2: Pick Events';
            app.GridLayout = uigridlayout(app.Step2PickEventsTab);
            app.UIAxes2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Displacement (μm)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Displacement (mm)')
            ylabel(app.UIAxes, 'Friction, μ')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Displacement (μm)'; 'Friction'; 'Initial Velocity (μm/s)'; 'Final Velocity (μm/s)'; 'Index'; 'Hold Time (s)'};
            app.UITable.RowName = {};
            app.PickVelocityStepButton = uibutton(app.GridLayout, 'push');
            app.PickVelocityStepButton.ButtonPushedFcn = createCallbackFcn(app, @PickVelocityStepButtonPushed, true);
            app.PickVelocityStepButton.Text = 'Pick Velocity Step';
            app.AcceptPickButton = uibutton(app.GridLayout, 'push');
            app.AcceptPickButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptPickButtonPushed, true);
            app.AcceptPickButton.Text = 'Accept Pick';
            app.SaveTableButton = uibutton(app.GridLayout, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.OutputofStep1EditField = uieditfield(app.GridLayout, 'text');
            app.LoadFileButton = uibutton(app.GridLayout, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.OutputofStep1Label = uilabel(app.GridLayout);
            app.OutputofStep1Label.Text = '(Output of Step 1)';
            app.DeleteRowButton = uibutton(app.GridLayout, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.PickSlideHoldSlideButton = uibutton(app.GridLayout, 'push');
            app.PickSlideHoldSlideButton.ButtonPushedFcn = createCallbackFcn(app, @PickSlideHoldSlideButtonPushed, true);
            app.PickSlideHoldSlideButton.Text = 'Pick Slide-Hold-Slide';
            app.HoldTimesEditField = uieditfield(app.GridLayout, 'text');
            app.HoldTimesEditField.InputType = 'digits';
            app.HoldTimesEditField.Tag = 'HoldTime';
            app.text46 = uilabel(app.GridLayout);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Hold Duration (s)';
            app.FileNameLabel = uilabel(app.GridLayout);
            app.FileNameLabel.Text = 'File Name';
            app.EventFileNameEditField_2 = uieditfield(app.GridLayout, 'text');
            app.RowNumberEditFieldLabel = uilabel(app.GridLayout);
            app.RowNumberEditFieldLabel.Text = 'Row Number';
            app.RowNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofSigniciantFiguresforVelocitiesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofSigniciantFiguresforVelocitiesEditFieldLabel.Text = 'Number of Signiciant Figures for Velocities';
            app.VelocitySigFigEditField = uieditfield(app.GridLayout, 'numeric');
            app.VelocitySigFigEditField.Value = 1;
            app.Step3WindowandDetrendTab = uitab(app.TabGroup);
            app.Step3WindowandDetrendTab.Title = 'Step 3: Window and Detrend';
            app.UIAxes4 = uiaxes(app.Step3WindowandDetrendTab);
            title(app.UIAxes4, 'Fit Trend')
            xlabel(app.UIAxes4, 'Displacement (μm)')
            ylabel(app.UIAxes4, 'Friction, μ')
            zlabel(app.UIAxes4, 'Z')
            app.DetrendedAxes = uiaxes(app.Step3WindowandDetrendTab);
            title(app.DetrendedAxes, 'Detrended')
            xlabel(app.DetrendedAxes, 'Displacement (μm)')
            ylabel(app.DetrendedAxes, 'Friction, μ')
            zlabel(app.DetrendedAxes, 'Z')
            app.LoadTableButton = uibutton(app.Step3WindowandDetrendTab, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.AddtoTableButton = uibutton(app.Step3WindowandDetrendTab, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Text = 'Add to Table';
            app.UITable_2 = uitable(app.Step3WindowandDetrendTab);
            app.UITable_2.ColumnName = {'Event Number'; 'Displacement (μm)'; 'Friction'; 'Initial Velocity (μm/s)'; 'Final Velocity (μm/s)'; 'Index'; 'Hold Time (s)'; 'Start Index'; 'End Index'; 'Slope (1/μm)'; 'Reference Index'};
            app.UITable_2.RowName = {};
            app.SaveTableButton_2 = uibutton(app.Step3WindowandDetrendTab, 'push');
            app.SaveTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButton_2Pushed, true);
            app.SaveTableButton_2.Text = 'Save Table';
            app.DetrendPanel = uipanel(app.Step3WindowandDetrendTab);
            app.DetrendPanel.Title = 'Detrending Parameters';
            app.Point1SlipValue = uieditfield(app.DetrendPanel, 'text');
            app.DetrendButton_2 = uibutton(app.DetrendPanel, 'push');
            app.DetrendButton_2.ButtonPushedFcn = createCallbackFcn(app, @DetrendButton_2Pushed, true);
            app.DetrendButton_2.Text = 'Detrend';
            app.SlopeValue = uieditfield(app.DetrendPanel, 'text');
            app.latex_Slope = uilabel(app.DetrendPanel);
            app.latex_Slope.Text = 'slope (1 / μm)';
            app.Point2SlipValue = uieditfield(app.DetrendPanel, 'text');
            app.Point1MuValue = uieditfield(app.DetrendPanel, 'text');
            app.Point2MuValue = uieditfield(app.DetrendPanel, 'text');
            app.SetPoint1Button = uibutton(app.DetrendPanel, 'push');
            app.SetPoint1Button.ButtonPushedFcn = createCallbackFcn(app, @SetPoint1ButtonPushed, true);
            app.SetPoint1Button.Text = 'Set Point 1';
            app.FitTrendButton = uibutton(app.DetrendPanel, 'push');
            app.FitTrendButton.ButtonPushedFcn = createCallbackFcn(app, @FitTrendButtonPushed, true);
            app.FitTrendButton.Text = 'Fit Trend';
            app.text47 = uilabel(app.DetrendPanel);
            app.text47.Text = 'friction';
            app.text48 = uilabel(app.DetrendPanel);
            app.text48.Text = 'displacement';
            app.text49 = uilabel(app.DetrendPanel);
            app.text49.Text = 'friction';
            app.text50 = uilabel(app.DetrendPanel);
            app.text50.Text = 'displacement';
            app.SetPoint2Button = uibutton(app.DetrendPanel, 'push');
            app.SetPoint2Button.ButtonPushedFcn = createCallbackFcn(app, @SetPoint2ButtonPushed, true);
            app.SetPoint2Button.Text = 'Set Point 2';
            app.text55 = uilabel(app.DetrendPanel);
            app.text55.Text = {'Set slope to zero'; 'for no trend and'; 'clear points'};
            app.ReferencePointSlipValue = uieditfield(app.DetrendPanel, 'text');
            app.ReferencePointMuValue = uieditfield(app.DetrendPanel, 'text');
            app.text63 = uilabel(app.DetrendPanel);
            app.text63.Text = 'friction';
            app.text64 = uilabel(app.DetrendPanel);
            app.text64.Text = 'displacement';
            app.SetReferencePointButton = uibutton(app.DetrendPanel, 'push');
            app.SetReferencePointButton.ButtonPushedFcn = createCallbackFcn(app, @SetReferencePointButtonPushed, true);
            app.SetReferencePointButton.Text = 'Set Reference Point';
            app.ClearPointsButton = uibutton(app.DetrendPanel, 'push');
            app.ClearPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButtonPushed, true);
            app.ClearPointsButton.Text = 'Clear Points';
            app.EventLabel_2 = uilabel(app.Step3WindowandDetrendTab);
            app.EventLabel_2.Text = 'Event';
            app.EventListBox_Detrend = uilistbox(app.Step3WindowandDetrendTab);
            app.EventListBox_Detrend.Items = {};
            app.EventListBox_Detrend.ClickedFcn = createCallbackFcn(app, @EventListBox_DetrendClicked, true);
            app.EventListBox_Detrend.Value = {};
            app.FileNameLabel_7 = uilabel(app.Step3WindowandDetrendTab);
            app.FileNameLabel_7.Text = 'File Name';
            app.EventFileNameEditField = uieditfield(app.Step3WindowandDetrendTab, 'text');
            app.FileNameLabel_2 = uilabel(app.Step3WindowandDetrendTab);
            app.FileNameLabel_2.Text = 'File Name';
            app.SaveFileNameEditField = uieditfield(app.Step3WindowandDetrendTab, 'text');
            app.PresetDisplacementAmountmEditFieldLabel = uilabel(app.Step3WindowandDetrendTab);
            app.PresetDisplacementAmountmEditFieldLabel.Text = 'Preset Displacement Amount (μm)';
            app.PresetDisplacementAmountmEditField = uieditfield(app.Step3WindowandDetrendTab, 'numeric');
            app.PresetDisplacementAmountmEditField.Value = 500;
            app.IndividualFits = uitab(app.TabGroup);
            app.IndividualFits.Title = 'Step 4A: Individual Fits';
            app.FittingAxes = uiaxes(app.IndividualFits);
            xlabel(app.FittingAxes, 'Displacement (μm)')
            ylabel(app.FittingAxes, 'Friction, μ')
            app.WeightAxes = uiaxes(app.IndividualFits);
            xlabel(app.WeightAxes, 'Displacement (μm)')
            ylabel(app.WeightAxes, 'Weight')
            app.ClearTestLines = uibutton(app.IndividualFits, 'push');
            app.ClearTestLines.ButtonPushedFcn = createCallbackFcn(app, @ClearTestLinesButtonPushed, true);
            app.ClearTestLines.Text = 'Clear Test Lines';
            app.LocNormStress = uieditfield(app.IndividualFits, 'text');
            app.text67 = uilabel(app.IndividualFits);
            app.text67.Text = {'Normal Stress at'; 'Location (MPa)'};
            app.FittingOptions = uibuttongroup(app.IndividualFits);
            app.FittingOptions.Title = 'Fitting Options';
            app.WeightButton = uibutton(app.FittingOptions, 'state');
            app.WeightButton.ValueChangedFcn = createCallbackFcn(app, @WeightButtonValueChanged, true);
            app.WeightButton.Text = 'Use Weight Function';
            app.MuButton = uibutton(app.FittingOptions, 'state');
            app.MuButton.Text = 'Constrain μ₀';
            app.StiffnessButton = uibutton(app.FittingOptions, 'state');
            app.StiffnessButton.Text = 'Constrain Stiffness';
            app.FileNameLabel_6 = uilabel(app.IndividualFits);
            app.FileNameLabel_6.Text = 'File Name';
            app.EventFileNameEditField_4 = uieditfield(app.IndividualFits, 'text');
            app.UITable_Fit = uitable(app.IndividualFits);
            app.UITable_Fit.ColumnName = {'Event Number'; 'μ_0'; 'a'; 'b_1'; 'b_2'; 'dc1'; 'dc2'; 'Stiffness (1/μm)'; 'Number of States'; 'Aging/Slip'};
            app.UITable_Fit.RowName = {};
            app.UITable_Fit.ColumnSortable = true;
            app.UITable_Fit.ColumnEditable = true;
            app.WeightPanel = uipanel(app.IndividualFits);
            app.WeightPanel.Title = 'Weight Parameters';
            app.text60 = uilabel(app.WeightPanel);
            app.text60.Text = 'displacement';
            app.text61 = uilabel(app.WeightPanel);
            app.text61.Text = 'friction';
            app.WeightSlipValue = uieditfield(app.WeightPanel, 'text');
            app.WeightLocButton = uibutton(app.WeightPanel, 'push');
            app.WeightLocButton.ButtonPushedFcn = createCallbackFcn(app, @WeightLocButtonPushed, true);
            app.WeightLocButton.Text = 'Set Weight Location';
            app.WeightFrictionValue = uieditfield(app.WeightPanel, 'text');
            app.text65 = uilabel(app.WeightPanel);
            app.text65.Text = {'Weight Function'; 'Exponent'};
            app.WeightExponent = uieditfield(app.WeightPanel, 'text');
            app.EventLabel = uilabel(app.IndividualFits);
            app.EventLabel.Text = 'Event';
            app.EventListBox = uilistbox(app.IndividualFits);
            app.EventListBox.Items = {};
            app.EventListBox.ClickedFcn = createCallbackFcn(app, @EventListBoxClicked, true);
            app.EventListBox.Value = {};
            app.SaveFitsButton = uibutton(app.IndividualFits, 'push');
            app.SaveFitsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFitsButtonPushed, true);
            app.SaveFitsButton.Text = 'Save Fits';
            app.FitStepButton = uibutton(app.IndividualFits, 'push');
            app.FitStepButton.ButtonPushedFcn = createCallbackFcn(app, @FitStepButtonPushed, true);
            app.FitStepButton.Text = 'Fit Step';
            app.TestButton = uibutton(app.IndividualFits, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.AddFitToTableButton = uibutton(app.IndividualFits, 'push');
            app.AddFitToTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddFitToTableButtonPushed, true);
            app.AddFitToTableButton.Text = 'Add to Table';
            app.LoadTableButton_2 = uibutton(app.IndividualFits, 'push');
            app.LoadTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButton_2Pushed, true);
            app.LoadTableButton_2.Text = 'Load Table';
            app.AgingFitParametersLabel = uilabel(app.IndividualFits);
            app.AgingFitParametersLabel.Text = 'Aging Fit Parameters';
            app.AgingFittingPanel = uipanel(app.IndividualFits);
            app.ParameterGuesses = uilabel(app.AgingFittingPanel);
            app.ParameterGuesses.Text = 'One-State Guess';
            app.Guess_muValue_a1 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_muValue_a1.ValueChangedFcn = createCallbackFcn(app, @Guess_muValue_a1ValueChanged, true);
            app.Guess_aValue_a1 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_aValue_a1.ValueChangedFcn = createCallbackFcn(app, @Guess_aValue_a1ValueChanged, true);
            app.Guess_bValue_a1 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_bValue_a1.ValueChangedFcn = createCallbackFcn(app, @Guess_bValue_a1ValueChanged, true);
            app.Guess_dcValue_a1 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_dcValue_a1.ValueChangedFcn = createCallbackFcn(app, @Guess_dcValue_a1ValueChanged, true);
            app.Guess_kValue_a1 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_kValue_a1.ValueChangedFcn = createCallbackFcn(app, @Guess_kValue_a1ValueChanged, true);
            app.Label = uilabel(app.AgingFittingPanel);
            app.Label.Text = 'μ0';
            app.aLabel = uilabel(app.AgingFittingPanel);
            app.aLabel.Text = 'a';
            app.b1Label = uilabel(app.AgingFittingPanel);
            app.b1Label.Text = 'b1';
            app.dcLabel = uilabel(app.AgingFittingPanel);
            app.dcLabel.Text = 'dc';
            app.k1mLabel = uilabel(app.AgingFittingPanel);
            app.k1mLabel.Text = 'k (1/μm)';
            app.text52_4 = uilabel(app.AgingFittingPanel);
            app.text52_4.Text = 'b₂';
            app.text53_4 = uilabel(app.AgingFittingPanel);
            app.text53_4.Text = 'dc₂';
            app.Guess_muValue_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_muValue_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_muValue_a2ValueChanged, true);
            app.Guess_muValue_a2.Enable = 'off';
            app.Guess_aValue_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_aValue_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_aValue_a2ValueChanged, true);
            app.Guess_aValue_a2.Enable = 'off';
            app.Guess_bValue_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_bValue_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_bValue_a2ValueChanged, true);
            app.Guess_bValue_a2.Enable = 'off';
            app.Guess_dcValue_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_dcValue_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_dcValue_a2ValueChanged, true);
            app.Guess_dcValue_a2.Enable = 'off';
            app.Guess_kValue_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_kValue_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_kValue_a2ValueChanged, true);
            app.Guess_kValue_a2.Enable = 'off';
            app.Guess_b2Value_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_b2Value_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_b2Value_a2ValueChanged, true);
            app.Guess_b2Value_a2.Enable = 'off';
            app.Guess_dc2Value_a2 = uieditfield(app.AgingFittingPanel, 'text');
            app.Guess_dc2Value_a2.ValueChangedFcn = createCallbackFcn(app, @Guess_dc2Value_a2ValueChanged, true);
            app.Guess_dc2Value_a2.Enable = 'off';
            app.AgingTwoStateGuessButton = uibutton(app.AgingFittingPanel, 'state');
            app.AgingTwoStateGuessButton.ValueChangedFcn = createCallbackFcn(app, @AgingTwoStateGuessButtonValueChanged, true);
            app.AgingTwoStateGuessButton.Text = {'Two-State'; 'Guess'};
            app.SlipFittingPanel = uipanel(app.IndividualFits);
            app.ParameterGuesses_5 = uilabel(app.SlipFittingPanel);
            app.ParameterGuesses_5.Text = 'One-State Guess';
            app.Guess_muValue_s1 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_aValue_s1 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_bValue_s1 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_dcValue_s1 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_kValue_s1 = uieditfield(app.SlipFittingPanel, 'text');
            app.Label_2 = uilabel(app.SlipFittingPanel);
            app.Label_2.Text = 'μ0';
            app.aLabel_2 = uilabel(app.SlipFittingPanel);
            app.aLabel_2.Text = 'a';
            app.b1Label_2 = uilabel(app.SlipFittingPanel);
            app.b1Label_2.Text = 'b1';
            app.dcLabel_2 = uilabel(app.SlipFittingPanel);
            app.dcLabel_2.Text = 'dc';
            app.k1mLabel_2 = uilabel(app.SlipFittingPanel);
            app.k1mLabel_2.Text = 'k (1/μm)';
            app.Guess_muValue_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_muValue_s2.Enable = 'off';
            app.Guess_aValue_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_aValue_s2.Enable = 'off';
            app.Guess_bValue_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_bValue_s2.Enable = 'off';
            app.Guess_dcValue_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_dcValue_s2.Enable = 'off';
            app.Guess_kValue_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_kValue_s2.Enable = 'off';
            app.Guess_b2Value_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_b2Value_s2.Enable = 'off';
            app.Guess_dc2Value_s2 = uieditfield(app.SlipFittingPanel, 'text');
            app.Guess_dc2Value_s2.Enable = 'off';
            app.text52_5 = uilabel(app.SlipFittingPanel);
            app.text52_5.Text = 'b₂';
            app.text53_5 = uilabel(app.SlipFittingPanel);
            app.text53_5.Text = 'dc₂';
            app.SlipTwoStateGuessButton = uibutton(app.SlipFittingPanel, 'state');
            app.SlipTwoStateGuessButton.ValueChangedFcn = createCallbackFcn(app, @SlipTwoStateGuessButtonValueChanged, true);
            app.SlipTwoStateGuessButton.Text = {'Two-State'; 'Guess'};
            app.AgingCheckBox = uicheckbox(app.IndividualFits);
            app.AgingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AgingCheckBoxValueChanged, true);
            app.AgingCheckBox.Text = '';
            app.AgingCheckBox.Value = true;
            app.SlipFitParametersLabel = uilabel(app.IndividualFits);
            app.SlipFitParametersLabel.Text = 'Slip Fit Parameters';
            app.SlipCheckBox = uicheckbox(app.IndividualFits);
            app.SlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlipCheckBoxValueChanged, true);
            app.SlipCheckBox.Text = '';
            app.SlipCheckBox.Value = true;
            app.FileNameLabel_3 = uilabel(app.IndividualFits);
            app.FileNameLabel_3.Text = 'File Name';
            app.SaveFileNameEditField_2 = uieditfield(app.IndividualFits, 'text');
            app.Image = uiimage(app.IndividualFits);
            app.Image.ImageSource = 'RSFitLogo_small.jpg';
            app.LoadingStiffness1mEditFieldLabel = uilabel(app.IndividualFits);
            app.LoadingStiffness1mEditFieldLabel.Text = 'Loading Stiffness (1/μm)';
            app.LoadingStiffness1mEditField = uieditfield(app.IndividualFits, 'text');
            app.UnLoadingStiffness1mEditFieldLabel = uilabel(app.IndividualFits);
            app.UnLoadingStiffness1mEditFieldLabel.Text = 'Unloading Stiffness (1/μm)';
            app.UnLoadingStiffness1mEditField = uieditfield(app.IndividualFits, 'text');
            app.DownsampleButton = uibutton(app.IndividualFits, 'state');
            app.DownsampleButton.Text = 'Downsample';
            app.DownsampleFactorLabel = uilabel(app.IndividualFits);
            app.DownsampleFactorLabel.Text = 'Downsample Factor (initiates only when step 3 file loaded and downsample button activated)';
            app.DownsampleFactorEditField = uieditfield(app.IndividualFits, 'numeric');
            app.DownsampleFactorEditField.AllowEmpty = 'on';
            app.GlobalFit = uitab(app.TabGroup);
            app.GlobalFit.Title = 'Step 4B: Global Fit (work in progress)';
            app.LocNormStress_2 = uieditfield(app.GlobalFit, 'text');
            app.text67_2 = uilabel(app.GlobalFit);
            app.text67_2.Text = {'Normal Stress at'; 'Location (MPa)'};
            app.FittingOptions_2 = uibuttongroup(app.GlobalFit);
            app.FittingOptions_2.Title = 'Fitting Options';
            app.MuButton_2 = uibutton(app.FittingOptions_2, 'state');
            app.MuButton_2.Text = 'Constrain μ₀';
            app.StiffnessButton_2 = uibutton(app.FittingOptions_2, 'state');
            app.StiffnessButton_2.Text = 'Constrain Stiffness';
            app.UITable_GlobalFit = uitable(app.GlobalFit);
            app.UITable_GlobalFit.ColumnName = {'μ_0'; 'a'; 'b_1'; 'b_2'; 'dc1'; 'dc2'; 'Stiffness (1/μm)'; 'Number of States'; 'Aging/Slip'};
            app.UITable_GlobalFit.RowName = {};
            app.UITable_GlobalFit.ColumnSortable = true;
            app.UITable_GlobalFit.ColumnEditable = true;
            app.SingleEventDropDown = uidropdown(app.GlobalFit);
            app.SingleEventDropDown.ValueChangedFcn = createCallbackFcn(app, @SingleEventDropDownValueChanged, true);
            app.LoadTableButton_Global = uibutton(app.GlobalFit, 'push');
            app.LoadTableButton_Global.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButton_GlobalPushed, true);
            app.LoadTableButton_Global.Text = 'Load Table';
            app.FileNameLabel_5 = uilabel(app.GlobalFit);
            app.FileNameLabel_5.Text = 'File Name';
            app.EventFileNameEditField_Global = uieditfield(app.GlobalFit, 'text');
            app.FitButton_Global = uibutton(app.GlobalFit, 'push');
            app.FitButton_Global.Text = 'Fit';
            app.LoadingStiffness1mEditFieldLabel_2 = uilabel(app.GlobalFit);
            app.LoadingStiffness1mEditFieldLabel_2.Text = 'Loading Stiffness (1/μm)';
            app.UnLoadingStiffness1mEditFieldLabel_2 = uilabel(app.GlobalFit);
            app.UnLoadingStiffness1mEditFieldLabel_2.Text = 'Unloading Stiffness (1/μm)';
            app.UnLoadingStiffness1mEditField_2 = uieditfield(app.GlobalFit, 'text');
            app.LoadingStiffness1mEditField_2 = uieditfield(app.GlobalFit, 'text');
            app.AgingFitParametersLabel_2 = uilabel(app.GlobalFit);
            app.AgingFitParametersLabel_2.Text = 'Aging Fit Parameters';
            app.AgingFittingPanel_Global = uipanel(app.GlobalFit);
            app.ParameterGuesses_6 = uilabel(app.AgingFittingPanel_Global);
            app.ParameterGuesses_6.Text = 'One-State Guess';
            app.Guess_aValue_a1_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_aValue_a1_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_aValue_a1_GlobalValueChanged, true);
            app.Guess_bValue_a1_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_bValue_a1_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_bValue_a1_GlobalValueChanged, true);
            app.Guess_dcValue_a1_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_dcValue_a1_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_dcValue_a1_GlobalValueChanged, true);
            app.Guess_kValue_a1_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_kValue_a1_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_kValue_a1_GlobalValueChanged, true);
            app.aLabel_Global = uilabel(app.AgingFittingPanel_Global);
            app.aLabel_Global.Text = 'a';
            app.b1Label_Global = uilabel(app.AgingFittingPanel_Global);
            app.b1Label_Global.Text = 'b1';
            app.dcLabel_Global = uilabel(app.AgingFittingPanel_Global);
            app.dcLabel_Global.Text = 'dc';
            app.k1mLabel_Global = uilabel(app.AgingFittingPanel_Global);
            app.k1mLabel_Global.Text = 'k (1/μm)';
            app.text52_6 = uilabel(app.AgingFittingPanel_Global);
            app.text52_6.Text = 'b₂';
            app.text53_6 = uilabel(app.AgingFittingPanel_Global);
            app.text53_6.Text = 'dc₂';
            app.Guess_aValue_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_aValue_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_aValue_a2_GlobalValueChanged, true);
            app.Guess_aValue_a2_Global.Enable = 'off';
            app.Guess_bValue_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_bValue_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_bValue_a2_GlobalValueChanged, true);
            app.Guess_bValue_a2_Global.Enable = 'off';
            app.Guess_dcValue_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_dcValue_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_dcValue_a2_GlobalValueChanged, true);
            app.Guess_dcValue_a2_Global.Enable = 'off';
            app.Guess_kValue_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_kValue_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_kValue_a2_GlobalValueChanged, true);
            app.Guess_kValue_a2_Global.Enable = 'off';
            app.Guess_b2Value_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_b2Value_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_b2Value_a2_GlobalValueChanged, true);
            app.Guess_b2Value_a2_Global.Enable = 'off';
            app.Guess_dc2Value_a2_Global = uieditfield(app.AgingFittingPanel_Global, 'text');
            app.Guess_dc2Value_a2_Global.ValueChangedFcn = createCallbackFcn(app, @Guess_dc2Value_a2_GlobalValueChanged, true);
            app.Guess_dc2Value_a2_Global.Enable = 'off';
            app.AgingTwoStateGuessButton_Global = uibutton(app.AgingFittingPanel_Global, 'state');
            app.AgingTwoStateGuessButton_Global.ValueChangedFcn = createCallbackFcn(app, @AgingTwoStateGuessButton_GlobalValueChanged, true);
            app.AgingTwoStateGuessButton_Global.Text = {'Two-State'; 'Guess'};
            app.SlipFittingPanel_Global = uipanel(app.GlobalFit);
            app.ParameterGuesses_7 = uilabel(app.SlipFittingPanel_Global);
            app.ParameterGuesses_7.Text = 'One-State Guess';
            app.Guess_aValue_s1_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_bValue_s1_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_dcValue_s1_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_kValue_s1_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.aLabel_4 = uilabel(app.SlipFittingPanel_Global);
            app.aLabel_4.Text = 'a';
            app.b1Label_4 = uilabel(app.SlipFittingPanel_Global);
            app.b1Label_4.Text = 'b1';
            app.dcLabel_4 = uilabel(app.SlipFittingPanel_Global);
            app.dcLabel_4.Text = 'dc';
            app.k1mLabel_4 = uilabel(app.SlipFittingPanel_Global);
            app.k1mLabel_4.Text = 'k (1/μm)';
            app.Guess_aValue_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_aValue_s2_Global.Enable = 'off';
            app.Guess_bValue_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_bValue_s2_Global.Enable = 'off';
            app.Guess_dcValue_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_dcValue_s2_Global.Enable = 'off';
            app.Guess_kValue_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_kValue_s2_Global.Enable = 'off';
            app.Guess_b2Value_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_b2Value_s2_Global.Enable = 'off';
            app.Guess_dc2Value_s2_Global = uieditfield(app.SlipFittingPanel_Global, 'text');
            app.Guess_dc2Value_s2_Global.Enable = 'off';
            app.text52_7 = uilabel(app.SlipFittingPanel_Global);
            app.text52_7.Text = 'b₂';
            app.text53_7 = uilabel(app.SlipFittingPanel_Global);
            app.text53_7.Text = 'dc₂';
            app.SlipTwoStateGuessButton_Global = uibutton(app.SlipFittingPanel_Global, 'state');
            app.SlipTwoStateGuessButton_Global.ValueChangedFcn = createCallbackFcn(app, @SlipTwoStateGuessButton_GlobalValueChanged, true);
            app.SlipTwoStateGuessButton_Global.Text = {'Two-State'; 'Guess'};
            app.AgingCheckBox_Global = uicheckbox(app.GlobalFit);
            app.AgingCheckBox_Global.ValueChangedFcn = createCallbackFcn(app, @AgingCheckBox_GlobalValueChanged, true);
            app.AgingCheckBox_Global.Text = '';
            app.AgingCheckBox_Global.Value = true;
            app.SlipFitParametersLabel_2 = uilabel(app.GlobalFit);
            app.SlipFitParametersLabel_2.Text = 'Slip Fit Parameters';
            app.SlipCheckBox_Global = uicheckbox(app.GlobalFit);
            app.SlipCheckBox_Global.ValueChangedFcn = createCallbackFcn(app, @SlipCheckBox_GlobalValueChanged, true);
            app.SlipCheckBox_Global.Text = '';
            app.SlipCheckBox_Global.Value = true;
            app.ClearTestLines_3 = uibutton(app.GlobalFit, 'push');
            app.ClearTestLines_3.Text = 'Clear Test Lines';
            app.AcceptFit = uibutton(app.GlobalFit, 'push');
            app.AcceptFit.Text = 'Accept Fit';
            app.SingleEventDropDownLabel = uilabel(app.GlobalFit);
            app.SingleEventDropDownLabel.Text = 'Single Event';
            app.Image_2 = uiimage(app.GlobalFit);
            app.Image_2.ImageSource = 'RSFitLogo_small.jpg';
            app.SaveFitButton = uibutton(app.GlobalFit, 'push');
            app.SaveFitButton.Text = 'Save Fit';
            app.FileNameLabel_4 = uilabel(app.GlobalFit);
            app.FileNameLabel_4.Text = 'File Name';
            app.SaveFileNameEditField_3 = uieditfield(app.GlobalFit, 'text');
            app.TabGroup2 = uitabgroup(app.GlobalFit);
            app.FullExperimentTab = uitab(app.TabGroup2);
            app.FullExperimentTab.Title = 'Full Experiment';
            app.UIAxes_FullExperiment = uiaxes(app.FullExperimentTab);
            xlabel(app.UIAxes_FullExperiment, 'Displacement (μm)')
            ylabel(app.UIAxes_FullExperiment, 'Friction, μ')
            zlabel(app.UIAxes_FullExperiment, 'Z')
            app.SingleEventTab = uitab(app.TabGroup2);
            app.SingleEventTab.Title = 'Single Event';
            app.UIAxes_SingleStep = uiaxes(app.SingleEventTab);
            xlabel(app.UIAxes_SingleStep, 'Displacement (μm)')
            ylabel(app.UIAxes_SingleStep, 'Friction, μ')
            zlabel(app.UIAxes_SingleStep, 'Z')
            app.TypeofEventstoIncludePanel = uipanel(app.GlobalFit);
            app.TypeofEventstoIncludePanel.Title = 'Type of Events to Include';
            app.VS_Up_Button = uibutton(app.TypeofEventstoIncludePanel, 'state');
            app.VS_Up_Button.Text = 'Velocity Step Up';
            app.VS_Up_Button.Value = true;
            app.VS_Down_Button = uibutton(app.TypeofEventstoIncludePanel, 'state');
            app.VS_Down_Button.Text = 'Velocity Step Down';
            app.VS_Down_Button.Value = true;
            app.SHS_Button = uibutton(app.TypeofEventstoIncludePanel, 'state');
            app.SHS_Button.Text = 'Slide-Hold-Slides';
            app.SHS_Button.Value = true;
            app.text55_4 = uilabel(app.GlobalFit);
            app.text55_4.Text = 'Must save a new file for each event type if doing separate global fits for different event types';
            app.TestButton_Global = uibutton(app.GlobalFit, 'state');
            app.TestButton_Global.ValueChangedFcn = createCallbackFcn(app, @TestButton_GlobalValueChanged, true);
            app.TestButton_Global.Text = 'Test';
            app.text55_5 = uilabel(app.GlobalFit);
            app.text55_5.Text = 'sets μ₀ as mean friction of time period before event';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSFit4000

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end